classdef PlotPseudosection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAGEMinPseudoSectionViewerUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        InstallMAGEMinMenu              matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        OpeninnewFigureMenu             matlab.ui.container.Menu
        ExporttoLaMEMGeoParamsMenu      matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        TabGroup3                       matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        SimulationinfoPanel             matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveFileName                    matlab.ui.control.Button
        DiagramtypeListBoxLabel         matlab.ui.control.Label
        DiagramtypeListBox              matlab.ui.control.ListBox
        SimulationParametersPanel       matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        CoarsegridresolutionLabel       matlab.ui.control.Label
        RefinementlevelsDropDownLabel   matlab.ui.control.Label
        RefinementlevelsDropDown        matlab.ui.control.DropDown
        VerboselevelDropDownLabel       matlab.ui.control.Label
        VerboselevelDropDown            matlab.ui.control.DropDown
        ParallelcomputationsCheckBox    matlab.ui.control.CheckBox
        GammaestimationCheckBox         matlab.ui.control.CheckBox
        MPIRanksEditFieldLabel_2        matlab.ui.control.EditField
        MPIRanksEditFieldLabel_1        matlab.ui.control.Label
        MPIRanksEditFieldLabel_3        matlab.ui.control.Label
        MPIRanksEditFieldLabel_4        matlab.ui.control.EditField
        SelectMPIexec                   matlab.ui.control.Button
        UsexEOSuponrefinementCheckBox   matlab.ui.control.CheckBox
        xEOSDropDown                    matlab.ui.control.DropDown
        num_Neighbours                  matlab.ui.control.NumericEditField
        Gamma_Dropdown                  matlab.ui.control.DropDown
        EMFracDropDown                  matlab.ui.control.DropDown
        UseEMFractionuponrefinementCheckBox  matlab.ui.control.CheckBox
        num_Neighbours_EMFrac           matlab.ui.control.NumericEditField
        PerformcomputationsonremoteserverCheckBox  matlab.ui.control.CheckBox
        MAGEMinexecutableLabel          matlab.ui.control.Label
        Binary_Switch                   matlab.ui.control.Switch
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        PressureLabel                   matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        PressureEditField               matlab.ui.control.NumericEditField
        ChemicalcompositionPanel        matlab.ui.container.Panel
        UITableChemistry                matlab.ui.control.Table
        PredefinedtestDropDownLabel     matlab.ui.control.Label
        PredefinedtestDropDown          matlab.ui.control.DropDown
        Switch                          matlab.ui.control.Switch
        LoadfromfileButton              matlab.ui.control.Button
        SystemunitDropDown              matlab.ui.control.DropDown
        BulkCaption                     matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ThermodynamicsPanel             matlab.ui.container.Panel
        DatabaseListBoxLabel            matlab.ui.control.Label
        DatabaseListBox                 matlab.ui.control.ListBox
        StartnewcomputationButton       matlab.ui.control.Button
        PseudosectionTab                matlab.ui.container.Tab
        NumPointsLabel                  matlab.ui.control.Label
        ExportfigureButton              matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        StableAssemblagePanel           matlab.ui.container.Panel
        UITableStableAssemblage         matlab.ui.control.Table
        InformationPanel                matlab.ui.container.Panel
        TextAreaInfo                    matlab.ui.control.TextArea
        SaveequilibriumdataButton       matlab.ui.control.Button
        DisplayfieldDropDownLabel       matlab.ui.control.Label
        DisplayfieldDropDown            matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        RefineTab                       matlab.ui.container.Tab
        RefinegridcalculationsLabel     matlab.ui.control.Label
        StartcomputationButton          matlab.ui.control.Button
        ShowcellcentersselectpointsButton  matlab.ui.control.Button
        RefineselectedareaButton        matlab.ui.control.Button
        RefineallphaseboundariesButton  matlab.ui.control.Button
        newpointsTextAreaLabel          matlab.ui.control.Label
        newpointsTextArea               matlab.ui.control.TextArea
        UniformrefinementButton         matlab.ui.control.Button
        RefineunivariantpointsButton    matlab.ui.control.Button
        ExtractBoundariesTab            matlab.ui.container.Tab
        PickField1Button                matlab.ui.control.Button
        PickField2Button                matlab.ui.control.Button
        ReactionlineTextArea            matlab.ui.control.TextArea
        ReactionLabel                   matlab.ui.control.Label
        ExtractBoundButton              matlab.ui.control.Button
        RemovePointsButton              matlab.ui.control.Button
        ComputeTCLineButton             matlab.ui.control.Button
        PEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditField_2                    matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        PEditField_3                    matlab.ui.control.NumericEditField
        scanPCheckBox                   matlab.ui.control.CheckBox
        pointsEditFieldLabel            matlab.ui.control.Label
        pointsEditField                 matlab.ui.control.NumericEditField
        DeletelastlineButton            matlab.ui.control.Button
        DeletealllinesButton            matlab.ui.control.Button
        ComputeLineButton               matlab.ui.control.Button
        RedoTab                         matlab.ui.container.Tab
        RedocomputationsforselectedpointsLabel  matlab.ui.control.Label
        ShowselectpointsButton          matlab.ui.control.Button
        fordebuggingLabel               matlab.ui.control.Label
        StartRedoComputationButton      matlab.ui.control.Button
        StartRedoStatusPoints           matlab.ui.control.Button
        ReprocessPointsMinFraction      matlab.ui.control.Button
        SelectAllPoints                 matlab.ui.control.Button
        StartRedoComputationTCButton    matlab.ui.control.Button
        SelectAllNewPoints              matlab.ui.control.Button
        SelectAllNewPoints_noTCButton   matlab.ui.control.Button
        SolutionspaceTab                matlab.ui.container.Tab
        AnalyzeSolutionSpaceLabel       matlab.ui.control.Label
        solutionmodelEditFieldLabel     matlab.ui.control.Label
        solutionmodelEditField          matlab.ui.control.EditField
        pointsdimensionEditFieldLabel   matlab.ui.control.Label
        pointsdimensionEditField        matlab.ui.control.NumericEditField
        ThiswillmakeanexhaustivesearchoftheLabel  matlab.ui.control.Label
        DoLocalMinimizationsButton      matlab.ui.control.Button
        PlotoutputLocalOptimizationButton  matlab.ui.control.Button
        DropDown_Local_Param1           matlab.ui.control.DropDown
        DropDown_Local_Param2           matlab.ui.control.DropDown
        Label_xaxis_Local               matlab.ui.control.Label
        Label_yaxis_Local               matlab.ui.control.Label
        PlotGsurface_Button             matlab.ui.control.Button
        Local_Xmin                      matlab.ui.control.NumericEditField
        Local_Xmax                      matlab.ui.control.NumericEditField
        Local_Xpts                      matlab.ui.control.NumericEditField
        Label_Xtext                     matlab.ui.control.Label
        Local_Ypts                      matlab.ui.control.NumericEditField
        Label_Ytext                     matlab.ui.control.Label
        Local_Ymax                      matlab.ui.control.NumericEditField
        Local_Ymin                      matlab.ui.control.NumericEditField
        clusterDropDownLabel            matlab.ui.control.Label
        clusterDropDown                 matlab.ui.control.DropDown
        keepnormalCheckBox              matlab.ui.control.CheckBox
        CheckBox_AddNN_local            matlab.ui.control.CheckBox
        CheckBox_holdon_local           matlab.ui.control.CheckBox
        CheckBox_FullSurface            matlab.ui.control.CheckBox
        ShowedgesSwitchLabel            matlab.ui.control.Label
        ShowedgesSwitch                 matlab.ui.control.Switch
        ComputeTCsolutionforeverypointCheckBox  matlab.ui.control.CheckBox
        Image2                          matlab.ui.control.Image
        v131Label                       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup4                       matlab.ui.container.TabGroup
        PlotoptionsTab                  matlab.ui.container.Tab
        TCLines_LineColor_3             matlab.ui.control.EditField
        styleLabel_4                    matlab.ui.control.Label
        TCLabel                         matlab.ui.control.Label
        widthEditFieldLabel_3           matlab.ui.control.Label
        TCLines_LineWidth_3             matlab.ui.control.NumericEditField
        PlotCheckBox                    matlab.ui.control.CheckBox
        PointLabel                      matlab.ui.control.Label
        sizeLabel                       matlab.ui.control.Label
        SelectPt_MarkerSize             matlab.ui.control.NumericEditField
        styleLabel_5                    matlab.ui.control.Label
        SelectPt_MarkerStyle            matlab.ui.control.EditField
        widthLabel                      matlab.ui.control.Label
        SelectPt_MarkerWidth            matlab.ui.control.NumericEditField
        AxesFontsizeLabel               matlab.ui.control.Label
        Axes_Fontsize                   matlab.ui.control.NumericEditField
        TitleFontsizeLabel              matlab.ui.control.Label
        Title_Fontsize                  matlab.ui.control.NumericEditField
        ColorbarlabelLabel              matlab.ui.control.Label
        Colorbarlabel                   matlab.ui.control.EditField
        ColoraxisLabel                  matlab.ui.control.Label
        Caxis_min                       matlab.ui.control.NumericEditField
        Caxis_max                       matlab.ui.control.NumericEditField
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        AddphaselabelsCheckBox          matlab.ui.control.CheckBox
        removealllabelsButton           matlab.ui.control.Button
        removelastlabelButton           matlab.ui.control.Button
        AddlabellineCheckBox            matlab.ui.control.CheckBox
        IsocontourTab                   matlab.ui.container.Tab
        DisplayisocontourLabel          matlab.ui.control.Label
        displayisocontourButton         matlab.ui.control.Button
        removeallButton                 matlab.ui.control.Button
        TCLines_LineColor_2             matlab.ui.control.EditField
        StyleLabel                      matlab.ui.control.Label
        WidthLabel                      matlab.ui.control.Label
        TCLines_LineWidth_2             matlab.ui.control.NumericEditField
        phaseEditField                  matlab.ui.control.EditField
        phaseEditFieldLabel             matlab.ui.control.Label
        minEditField                    matlab.ui.control.EditField
        minEditFieldLabel               matlab.ui.control.Label
        stepEditField                   matlab.ui.control.EditField
        stepEditFieldLabel              matlab.ui.control.Label
        maxEditField                    matlab.ui.control.EditField
        maxEditFieldLabel               matlab.ui.control.Label
        resEditField                    matlab.ui.control.EditField
        resEditFieldLabel               matlab.ui.control.Label
        DataListBox                     matlab.ui.control.ListBox
        emnamesListBox                  matlab.ui.control.ListBox
        TCLines_LineColor_4             matlab.ui.control.EditField
        ColorLabel                      matlab.ui.control.Label
        TCLines_LineColor_5             matlab.ui.control.EditField
        FontsizeLabel                   matlab.ui.control.Label
        Isopleth                        matlab.ui.control.Label
        Iso_label                       matlab.ui.control.Label
        removelastsButton               matlab.ui.control.Button
    end

    % load data
    properties (Access = public)
        PseudoSectionData
        newPoints
        PlotData
        PlotPhaseBounds
        PlotBrushData
        PTnum
        hField1
        hField2
        hCellCenter
        hPoints
        FileName
        FileDir
        PlotPoint
        hText
        hContour
        hContourHandle
        hContourLegendText
        hCaptionHandle
    end
    
    properties (Access = private)
        oldpath   =   addpath([pwd,filesep,'pics'])
        oldpath1  =   addpath([pwd,filesep,'matlab'])
    end
    
    
    methods (Access = private)
        
        % Plot initial phase diagram
        function [PseudoSectionData] = PlotInitialPseudoSection(app,PseudoSectionData)
            % Create color
            PhaseData   =   PseudoSectionData.PhaseData;
            
            % liquid fraction at every point
            liq         =   zeros(length(PhaseData),1);
            numPhase    =   zeros(length(PhaseData),1);
            Gibbs_vec   =   zeros(length(PhaseData),1);
            RhoTot_vec  =   zeros(length(PhaseData),1);
            RhoLiq_vec  =   zeros(length(PhaseData),1);
            RhoSol_vec  =   zeros(length(PhaseData),1);
            Vp_vec      =   zeros(length(PhaseData),1);
            Vs_vec      =   zeros(length(PhaseData),1);
            entropy_vec =   zeros(length(PhaseData),1);
            Status_vec  =   zeros(length(PhaseData),1);
            for i=1:length(PhaseData)
                liq(i)          =   PhaseData{i}.liq;
                numPhase(i)     =   PhaseData{i}.numStablePhases;
                Gibbs_vec(i)    =   PhaseData{i}.Gibbs;
                Gamma_vec(:,i)  =   PhaseData{i}.Gamma;
                RhoTot_vec(i)   =   PhaseData{i}.Density_total;
                RhoLiq_vec(i)   =   PhaseData{i}.Density_liq;
                RhoSol_vec(i)   =   PhaseData{i}.Density_sol;
                Vp_vec(i)       =   PhaseData{i}.Vp;
                Vs_vec(i)       =   PhaseData{i}.Vs;
                entropy_vec(i)  =   PhaseData{i}.entropy;
                Status_vec(i)   =   PhaseData{i}.Status;
                array(i).StableSolutions = PhaseData{i}.StableSolutions;
            end
            ReadPseudoSectionData_MAGEMin
            % 1) Give every solid solution and pure phase a number
            % 1a) Create a list with all stable phases/solutions in the diagram
            StableSol = array(1).StableSolutions;
            for i=1:length(array)
                sol = array(i).StableSolutions;
                for j=1:length(sol)
                    if ~(any(strcmp(StableSol,sol(j))))
                        StableSol(end+1)  = sol(j);
                    end
                end
                
            end
            StableSol = unique(StableSol);
            
            % 1b) Give them all a number
            % StablePhaseNumber = [1:length(StableSol)];  % NOTE: we may need to assign random numbers
            
            rng(0,'twister');   % reproducible random numbers
            StablePhaseNumber = randi([0 1e4],1,length(StableSol));
            
            
            % 1c)
            for i=1:length(array)
                NumAssemblage = 0;
                sol = unique(array(i).StableSolutions);
                for j=1:length(sol)
                    id = find(strcmp(StableSol,sol{j}));
                    
                    NumAssemblage = NumAssemblage + StablePhaseNumber(id);
                    
                end
                NumAssemblage_vec(i)       = NumAssemblage;
                PhaseData{i}.NumAssemblage = NumAssemblage;
            end
            
            
            % Display the # of points we currently have in the diagram
            app.NumPointsLabel.Text  = [num2str(length(PhaseData)),' points'];
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxes,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxes,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxes,upTitle)
            end
            set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
            % Store data so that they are available in other routines
            PseudoSectionData.liq                =   liq;
            PseudoSectionData.numPhase           =   numPhase;
            PseudoSectionData.Status             =   Status_vec;
            PseudoSectionData.Gibbs              =   Gibbs_vec;
            PseudoSectionData.Rho_tot            =   RhoTot_vec;
            PseudoSectionData.Rho_sol            =   RhoSol_vec;
            PseudoSectionData.Rho_liq            =   RhoLiq_vec;
            PseudoSectionData.Vp                 =   Vp_vec;
            PseudoSectionData.Vs                 =   Vs_vec;
            PseudoSectionData.entropy            =   entropy_vec;
            PseudoSectionData.Gamma              =   Gamma_vec;
            PseudoSectionData.PhaseData          =   PhaseData;
            PseudoSectionData.NumAssemblage      =   NumAssemblage_vec;
            PseudoSectionData.StablePhaseNumber  =   StablePhaseNumber;
            PseudoSectionData.StableSol          =   StableSol;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end
        
        function [OxideOxProportionsTable, Name] = GetPredefinedChemistry(app,Test,database);
            
            if database == "ig"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'Cr2O3'; 'H2O';};
                switch Test
                    case 0
                        OxProps = [  38.494  1.776  2.824 50.566 5.886  0.01  0.250  0.10  0.096  0.109 0]';
                        Name     = 'KLB-1';
                    case 1
                        OxProps = [ 50.72,	9.16,15.21, 16.25,	7.06	0.01 1.47 0.39 0.35  0.01  0.0]';
                        Name     = 'RE46';
                    case 2
                        OxProps = [ 53.21,	9.41,	12.21,	12.21,	8.65,	0.09,	2.90,1.21,0.69,0.02, 0.0]';
                        Name     = 'N-MORB';
                    case 3
                        OxProps = [ 45.25;	8.89;	12.22;	24.68;6.45;	0.03;	1.39;0.67;0.11;0.02;0.0;];
                        Name     = 'MIX1G';
                    case 4
                        OxProps = [ 54.40,	12.96, 11.31, 7.68, 8.63,	0.54, 3.93, 0.79, 0.41, 0.01, 0.0]';
                        Name     = 'High Al Basalt';
                    case 5
                        OxProps = [66.01,11.98,7.06,4.16,5.30,1.57,4.12,0.66,0.97,0.01, 50]';
                        Name     = 'Tonalite 101';
                    case 6
                        OxProps = [  50.0810  8.6901  11.6698 12.1438 7.7832  0.2150  2.4978  1.0059  0.4670  0.0100 5.4364]';
                        Name     = 'WetBasalt';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "mp"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'MnO'; 'H2O';};
                switch Test
                    case 0
                        % 1  World-Mean pelite
                        OxProps = [  71.00 12.81 0.77 3.98 6.34 2.79 1.48 0.76 0.1 0.075 40.0]';
                        Name     = 'WM-pelite-H2O-oversat';
                    case 1
                        OxProps = [ 71.00 12.81 0.77 3.98 6.34 2.79 1.48 0.76 0.1 0.075 5.0]';
                        Name     = 'WM-pelite-H2O-undersat';
                    case 2
                        OxProps = [ 64.578 13.651 1.586 5.529 8.025 2.943 2.000 0.907 0.65 0.175 40.0]';
                        Name     = 'Pelite-oversat';
                    case 3
                        OxProps = [ 64.578 13.651 1.586 5.529 8.025 2.943 2.000 0.907 0.65 0.175 6.244]';
                        Name     = 'Pelite-undersat';
                    case 4
                        OxProps = [ 73.9880 8.6143 2.0146 2.7401 3.8451 1.7686 2.4820 0.6393 0.11 0.0630  10.0]';
                        Name     = 'El-Oro-Metapelite';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            end
            OxideOxProportionsTable = table(OxideNames,OxProps,OxProps,'VariableNames',{'Oxide','mol %','mol2 %'});
            
        end
        
        
        function app = FillInSimulationTab(app, PseudoSectionData)
            % This fills in the various fields with the "Simulation" Tab
            % with values of the PseudoSection structure
            
            % As this routine is called at least twice (upon starting and
            % upon loading a new field), it is moved into a function
            
            % Set default parameters
            app.FilenameEditField.Value         =  PseudoSectionData.FileName;
            app.TitleEditField.Value            =  PseudoSectionData.Name;
            
            app.UITable.Data(1,:)               = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
            app.UITable.Data(2,:)               = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
         
            app.RefinementlevelsDropDown.Value                  =   num2str(PseudoSectionData.Computation.RefinementLevels);
            app.VerboselevelDropDown.Value                      =   num2str(PseudoSectionData.Computation.Verbose);
            app.SolverDropDown.Value                            =   PseudoSectionData.Computation.solver;
%             app.DatabaseDropDown.Value                          =   PseudoSectionData.Computation.db;
            app.DatabaseListBox.Value                           =   PseudoSectionData.Computation.db;
            app.ParallelcomputationsCheckBox.Value              =   PseudoSectionData.Computation.AllPoints;
            app.GammaestimationCheckBox.Value                   =   PseudoSectionData.Computation.UseGammaEstimation;
            app.MPIRanksEditFieldLabel_2.Value                  =   num2str(PseudoSectionData.Computation.NumRanks);
            app.MPIRanksEditFieldLabel_4.Value                  =   PseudoSectionData.Computation.MPI_dir;
            app.UsexEOSuponrefinementCheckBox.Value             =   PseudoSectionData.Computation.Use_xEOS;
            app.PerformcomputationsonremoteserverCheckBox.Value =   PseudoSectionData.Computation.RemoteServer;
            app.xEOSDropDown.Value                              =   PseudoSectionData.Computation.EOS_Method;
            
            if isfield(PseudoSectionData.Computation,'Gamma_Method')
                % for backwards compatibility - can be removed @ some stage
                app.Gamma_Dropdown.Value                              =   PseudoSectionData.Computation.Gamma_Method;
            end
            
            app.num_Neighbours.Value                            =   PseudoSectionData.Computation.num_Neighbours;
            
            
            % Grey out options
            value = app.ParallelcomputationsCheckBox.Value;
            if value 
                Enable = "on";
            else
                Enable = "off";
            end
            app.MPIRanksEditFieldLabel_1.Enable = Enable;
            app.MPIRanksEditFieldLabel_2.Enable = Enable;
            switch app.Binary_Switch.Value
                case 'Local'
                    app.MPIRanksEditFieldLabel_3.Enable = Enable;
                    app.MPIRanksEditFieldLabel_4.Enable = Enable;
                    app.SelectMPIexec.Enable            = Enable;
            end


            if ~isnan(PseudoSectionData.Chemistry.Predefined)
                % we use a build-in test with predefined chemistry
                Test                                =   PseudoSectionData.Chemistry.Predefined;
%                 database                            =   app.DatabaseDropDown.Value;
                database                            =   app.DatabaseListBox.Value;
                app.PredefinedtestDropDown.Value    =   num2str(Test);
                app.Switch.Value                    =   'Preset bulk';
                app.UITableChemistry.Enable         =   'off';
                
                % obtain chemistry for the predefined tests
                [OxideOxProportionsTable, Name]    =   GetPredefinedChemistry(app,Test,database);
                app.TitleEditField.Value            =   Name;
                
            else
                % we do not use a predefined chemistry
                app.Switch.Value                    =   'Custom bulk';
                OxideOxProportionsTable            =   PseudoSectionData.Chemistry.OxProp;
                app.UITableChemistry.Enable         =   'on';
            end
            app.UITableChemistry.Data = OxideOxProportionsTable; % set table
            value = app.DiagramtypeListBox.Value;
            if isfield(app,'UIAxes')
                if value == 'PT'
                        title(app.UIAxes,app.TitleEditField.Value)
                    elseif value == 'PX'
                        upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                        title(app.UIAxes,upTitle)
                    elseif value == 'TX'
                        upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                        title(app.UIAxes,upTitle)
                end
            end
            
        end
        
        
        function PseudoSectionData = GetDataFromSimulationTab(app, PseudoSectionData)
            % This reads in the various fields of the "Simulation" Tab
            % and puts the values in the PseudoSectionData structure (e.g.
            % before starting a new simulation)
            
            % As this routine is called at least twice (upon starting and
            % upon loading a new field), it is moved into a function
            
            % Set default parameters
            PseudoSectionData                   =   app.PseudoSectionData;
            
            PseudoSectionData.FileName          =   app.FilenameEditField.Value;
            PseudoSectionData.Name              =   app.TitleEditField.Value;
            
            if app.DiagramtypeListBox.Value         == 'PT'
                PseudoSectionData.CoarseGrid.P      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.T      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
            elseif app.DiagramtypeListBox.Value     == 'PX'
                PseudoSectionData.CoarseGrid.P      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.X      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
                PseudoSectionData.FixedTemperature  =  app.TemperatureEditField.Value;
            elseif app.DiagramtypeListBox.Value     == 'TX'
                PseudoSectionData.CoarseGrid.T      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.X      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
                PseudoSectionData.FixedPressure     =  app.PressureEditField.Value;
            end
            
            PseudoSectionData.Computation.RefinementLevels      =   str2num(app.RefinementlevelsDropDown.Value);
            PseudoSectionData.Computation.Verbose               =   str2num(app.VerboselevelDropDown.Value);
            PseudoSectionData.Computation.solver                =   app.SolverDropDown.Value;
%             PseudoSectionData.Computation.db                    =   app.DatabaseDropDown.Value;
            PseudoSectionData.Computation.db                    =   app.DatabaseListBox.Value;
            PseudoSectionData.Computation.DiagramType           =   app.DiagramtypeListBox.Value;
            PseudoSectionData.Computation.AllPoints             =   app.ParallelcomputationsCheckBox.Value;
            PseudoSectionData.Computation.UseGammaEstimation    =   app.GammaestimationCheckBox.Value;
            
            PseudoSectionData.Computation.Use_xEOS              =   app.UsexEOSuponrefinementCheckBox.Value;
            PseudoSectionData.Computation.EOS_Method            =   app.xEOSDropDown.Value;
            PseudoSectionData.Computation.num_Neighbours        =   app.num_Neighbours.Value;
            
            PseudoSectionData.Computation.Use_EMFrac            =   app.UseEMFractionuponrefinementCheckBox.Value;
            PseudoSectionData.Computation.EMFrac_Method         =   app.EMFracDropDown.Value;
            PseudoSectionData.Computation.num_Neighbours_EMFrac =   app.num_Neighbours_EMFrac.Value;
            
            PseudoSectionData.Computation.Gamma_Method          =   app.Gamma_Dropdown.Value;
            PseudoSectionData.Computation.NumRanks              =   str2num(app.MPIRanksEditFieldLabel_2.Value);
            PseudoSectionData.Computation.MPI_dir               =   app.MPIRanksEditFieldLabel_4.Value;
            PseudoSectionData.Computation.RemoteServer          =   app.PerformcomputationsonremoteserverCheckBox.Value;
            PseudoSectionData.Computation.MinPhaseFraction      =   0;  % can be removed later (was a GUI option; removed because of lack of useage)
            
            % retrieve prefered Binary method
            value = app.Binary_Switch.Value;
            PseudoSectionData.Computation.BinaryMethod          =   value;
            
            
            % Predefine chemistry or not?
            value = app.Switch.Value;
            switch value
                case 'Preset bulk'
                    PseudoSectionData.Chemistry.Predefined            =   str2num(app.PredefinedtestDropDown.Value);
                case 'Custom bulk'
                    PseudoSectionData.Chemistry.Predefined            =   NaN;    % do not use a predefined test
                otherwise
                    error('oops, something iffy')
            end
            
            % Obtain the chemical composition
            PseudoSectionData.Chemistry.sys_in                        =     'mol';
            PseudoSectionData.Chemistry.OxProp                       =     app.UITableChemistry.Data;
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end
        
        function PseudoSectionData = RedoSimulationFailedPoints(app, PseudoSectionData)
            
            PhaseData       =   PseudoSectionData.PhaseData;
            Status          =   zeros(length(PhaseData),1);
            for i=1:length(PhaseData)
                Status(i)   =   PhaseData{i}.Status;
            end
            
            newPoints           =   find(Status==3);
            
            if length(newPoints)>0
                
                % Perform a MAGEMin simulation for all new points
                PhaseData           =   PseudoSectionData.PhaseData;
                XY_vec              =   PseudoSectionData.XY_vec;
                %                 ComputeAllPoints    =   PseudoSectionData.Computation.AllPoints;
                ComputeAllPoints    =   false;
                VerboseLevel        =   PseudoSectionData.Computation.Verbose;
                Chemistry           =   PseudoSectionData.Chemistry;
                UseGammaEstimation  =   PseudoSectionData.Computation.UseGammaEstimation;
                
                Computation         =   PseudoSectionData.Computation;
                
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redo computations for selected points'],'Cancelable',"on");
                
                pause(1)
                [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PhaseData, newPoints, XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                
                PseudoSectionData.PhaseData = PhaseData;
                
                
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app_GUI = app;
            % center the GUI
            movegui(app.MAGEMinPseudoSectionViewerUIFigure,"center")
            
            % Default parameters
            PseudoSectionData.Name         = 'KLB-1';                       % Name/description/title of simulation
            PseudoSectionData.FileName     = 'test';                        % FileName of simulation
            
            PseudoSectionData.CoarseGrid.P = [0.01   24.01   4. ];          % Pressure [kbar]: min, max, step
            PseudoSectionData.CoarseGrid.T = [800. 1400. 100. ];            % Temperature [C]: min, max, step
            PseudoSectionData.CoarseGrid.X = [0. 1.0 0.2];                  % Composition [frac]: min, max, step
            PseudoSectionData.FixedTemperature  = 800.0;                    % default fixed temperature for PX diagrams
            PseudoSectionData.FixedPressure     = 10.0;                     % default fixed pressure for TX diagrams
            
            PseudoSectionData.CoarseGrid.contour = [0.   0.1   1.0 ];       % Pressure [kbar]: min, max, step
           
            % Computational parameters
            PseudoSectionData.activePoint                       = -1;
            PseudoSectionData.Computation.MatlabOut             = 0;        % activate output for matlab post-processing (by Tobias Keller)
            PseudoSectionData.Computation.solver                = 1;        % default solver (PGE), if 0 is used, the legacy solver will be used
            PseudoSectionData.Computation.db                    = "ig";     % default Igneous (Holland et al., 2018)
            PseudoSectionData.Computation.DiagramType           = "PT";     % default, PT; can also be PX TX.
            PseudoSectionData.Computation.RefinementLevels      = 2;        % # of refinement levels
            PseudoSectionData.Computation.Verbose               = 0;        % How much info will be shown?
            PseudoSectionData.Computation.AllPoints             = 1;        % Do we compute with all points or not?
            PseudoSectionData.Computation.UseGammaEstimation    = 0;        % Use previous gamma or not?
            PseudoSectionData.Computation.NumRanks              = 6;        % # of processors if we compute in parallel
            PseudoSectionData.Computation.MPI_dir               = '/usr/bin/';  % directory that has the MPI executable
            PseudoSectionData.Computation.RemoteServer          = logical(0);  % perform all computations on a remote server?
            PseudoSectionData.Computation.MinPhaseFraction      = 3e-3;     % Minimum require phase fraction, before we take a phase into account
            PseudoSectionData.Computation.num_Neighbours        = 9;
            PseudoSectionData.Computation.Use_xEOS              = logical(0);
            PseudoSectionData.Computation.EOS_Method            = 'Average surrounding points';
            PseudoSectionData.Chemistry.Predefined              = 0;        % Predefined chemical composition
            

            % Determine if we use the BinaryBuilder/Julia binary or a
            % locally compiled version of MAGEMin.
            path_dylib=[];
            path_bin=[];
            path_julia=[];
            if exist('environmental_variables','file')==2
                % yes, we did run the julia installation script @ some stage
                PseudoSectionData.Computation.Julia_MAGEMin_binary  =   true;
                
                environmental_variables;                                    % run script
                
                PseudoSectionData.Computation.path_dylib    = path_dylib;   % dynamic libraries
                PseudoSectionData.Computation.path_bin      = path_bin;     % MAGEMin & mpiexec binaries
                
            else
                PseudoSectionData.Computation.Julia_MAGEMin_binary = false;
            end
            
            % Check if a local MAGEMin version is available
            if exist('MAGEMin')==2 || exist('MAGEMin.exe')==2
                PseudoSectionData.Computation.Local_MAGEMin_binary = true;
            else
                PseudoSectionData.Computation.Local_MAGEMin_binary = false;
            end
            
            % set binary toggle
            if      PseudoSectionData.Computation.Julia_MAGEMin_binary==true && PseudoSectionData.Computation.Local_MAGEMin_binary==true
                app.Binary_Switch.Enable = true;
                app.Binary_Switch.Value = 'Local';
                PseudoSectionData.Computation.BinaryMethod = 'Local';
            elseif  PseudoSectionData.Computation.Julia_MAGEMin_binary==true && PseudoSectionData.Computation.Local_MAGEMin_binary==false
                app.Binary_Switch.Enable = false;
                app.Binary_Switch.Value = 'Default';
                PseudoSectionData.Computation.BinaryMethod = 'Default';
            elseif  PseudoSectionData.Computation.Julia_MAGEMin_binary==false && PseudoSectionData.Computation.Local_MAGEMin_binary==true
                app.Binary_Switch.Enable = false;
                app.Binary_Switch.Value = 'Local';
                PseudoSectionData.Computation.BinaryMethod = 'Local';
            else
                % No binary is available at all; switch color of button to
                % red
                app.Binary_Switch.FontColor=[1,0,0];
                PseudoSectionData.Computation.BinaryMethod = 'none';
                
                % Call installation GUI
                app_install = Install_MAGEMin;
                while isvalid(app_install); pause(0.1); end
                
            end
            
            
            % retrieve version number of executable
            if PseudoSectionData.Computation.Julia_MAGEMin_binary==true || PseudoSectionData.Computation.Local_MAGEMin_binary==true
                exe = MAGEMin_exe(PseudoSectionData.Computation);
                
                command = [exe,' --version'];
                if PseudoSectionData.Computation.Local_MAGEMin_binary==false
                    command = add_dynamic_libs(command, PseudoSectionData.Computation);
                end
                [stat, cmdout] = system(command);
                disp(cmdout)
                A=[];A=split(cmdout);
                PseudoSectionData.MAGEMin_Version = A(2);
                
            end
            
            % Set default parameters in simulation tab
            app = FillInSimulationTab(app, PseudoSectionData);
            app.PlotBrushData = 0;
            
            % Generate initial mesh, to initialize the plot
            
            % Generate initial (coarse) mesh & initial plot (to start
            % figure)
            
            if PseudoSectionData.Computation.DiagramType == 'PT'
                P_dat   = PseudoSectionData.CoarseGrid.P;
                T_dat   = PseudoSectionData.CoarseGrid.T;
                Y_vec1D =   P_dat(1):P_dat(3):P_dat(2);
                X_vec1D =   T_dat(1):T_dat(3):T_dat(2);  
            elseif PseudoSectionData.Computation.DiagramType == 'PX'
                P_dat   = PseudoSectionData.CoarseGrid.P;
                X_dat   = PseudoSectionData.CoarseGrid.X;
                Y_vec1D =   P_dat(1):P_dat(3):P_dat(2);
                X_vec1D =   X_dat(1):X_dat(3):X_dat(2);
            elseif PseudoSectionData.Computation.DiagramType == 'TX'
                T_dat   = PseudoSectionData.CoarseGrid.T;
                X_dat   = PseudoSectionData.CoarseGrid.X;
                Y_vec1D =   T_dat(1):T_dat(3):T_dat(2);  
                X_vec1D =   X_dat(1):X_dat(3):X_dat(2);
            end

            [XY_vec, elements, irregular]   =   GenerateRegularMesh_AMR(X_vec1D,Y_vec1D);
            PseudoSectionData.XY_vec        =   XY_vec;
            PseudoSectionData.elements      =   elements;
            
            
            %% This will be moved to a different file
            PTCH        =   patch(app.UIAxes,'Faces', PseudoSectionData.elements, ...
                'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',...
                PseudoSectionData.XY_vec(:,1)*0,'FaceColor','interp',...
                'ButtonDownFcn',@mouse_click, 'Linestyle','-');
            
            axis(app.UIAxes,'tight');
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxes,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxes,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxes,upTitle)
            end
            h       = colorbar(app.UIAxes);
            
            app.PlotData                =   PTCH;
            app.PseudoSectionData       =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % In case we click on the figure window
            function mouse_click(~,eventData)
                
                % get coordinates of click
                coords              =   eventData.IntersectionPoint;
                PseudoSectionData   = app.PseudoSectionData;
                
                % display stable assemblage
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                [k,dist]        =   dsearchn(TP_norm,[coords(1:2)]./NormVal);   % find closest point; use normalized values, because of the vast difference in values

                if isfield(PseudoSectionData,'PhaseData')
                    if app.DatabaseListBox.Value == 'ig'
                        db              =   'Igneous';
                    elseif app.DatabaseListBox.Value == 'mp'
                        db              =   'Metapelite';
                    end
%                     db              =   app.DatabaseListBox.Value;

                    Data            =   PseudoSectionData.PhaseData{k};
                   
                    
                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.FixedTemperature;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        P               =   PseudoSectionData.FixedPressure;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    end
                    
                    Gibbs           =   PseudoSectionData.PhaseData{k}.Gibbs;
                    Gamma           =   PseudoSectionData.PhaseData{k}.Gamma;
                    NumAssemblage   =   PseudoSectionData.NumAssemblage(k);
                    Density         =   PseudoSectionData.PhaseData{k}.Density_total;
                    MeltFraction    =   PseudoSectionData.PhaseData{k}.liq;
                    Vp              =   PseudoSectionData.PhaseData{k}.Vp;
                    Vs              =   PseudoSectionData.PhaseData{k}.Vs;
                    entropy         =   PseudoSectionData.PhaseData{k}.entropy;
                    Status          =   PseudoSectionData.PhaseData{k}.Status;
                    numStablePhases =   PseudoSectionData.PhaseData{k}.numStablePhases;
                    
                    % display info in info box
                    app.TextAreaInfo.Value{1} = ['database:   ',db];
                    app.TextAreaInfo.Value{2} = ['P             :   ',num2str(P,'%1.3f'),' kbar'];
                    app.TextAreaInfo.Value{3} = ['T             :   ',num2str(T,'%1.3f'),' Celcius'];
                    app.TextAreaInfo.Value{4} = ['Status     :   ',num2str(Status,'%i')];
                    app.TextAreaInfo.Value{5} = ['Num        :   ',num2str(NumAssemblage,'%i')];
                    app.TextAreaInfo.Value{6} = ['StPhase  :   ',num2str(numStablePhases,'%i')];
                    app.TextAreaInfo.Value{7} = ['Gibbs      :   ',num2str(Gibbs,'%1.6f')];
                    app.TextAreaInfo.Value{8} = ['rho          :   ',num2str(Density,'%1.0f'),' kg m-3'];
                    
                    %activate option to save full data of selected point
                    PseudoSectionData.activePoint                     = k;
                    app.SaveequilibriumdataButton.Enable              = true;
                    
                    % plot point
                    delete(app.PlotPoint)
                    hold(app.UIAxes,'on')
                    MarkerStyle = app.SelectPt_MarkerStyle.Value;
                    MarkerSize  = app.SelectPt_MarkerSize.Value;
                    MarkerWidth = app.SelectPt_MarkerWidth.Value;

                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        app.PlotPoint = plot(app.UIAxes,T,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        app.PlotPoint = plot(app.UIAxes,X,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        app.PlotPoint = plot(app.UIAxes,X,T,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    end
                    
%                     hold(app.UIAxes,'off')
                    % display stable assemblages and fractions
                    StablePhases    =   PseudoSectionData.PhaseData{k}.StableSolutions;
                    Fractions       =   PseudoSectionData.PhaseData{k}.StableFractions(:);
                    
                    t   =  table(StablePhases, Fractions);  % create a matlab table from it
                    app.UITableStableAssemblage.Data = t;   % add table to UI
                    
                    app.PTnum                   = [P T NumAssemblage];
                    
                    
                    % store info about the point we just clicked (useful for
                    % further computations at that point)
                    Data.Chemistry              = PseudoSectionData.Chemistry;  % store chemistry on data point as well
                    PointInfo                   = PseudoSectionData.PhaseData{k};
                    PseudoSectionData.PointInfo = PointInfo;
                    PseudoSectionData.Data      = Data;
                    app.PseudoSectionData       = PseudoSectionData;
                    
                    assignin('base','PseudoSectionData',PseudoSectionData);     % send to matlab command-line
                    assignin('base','Data', Data);                              % send info of current point to matlab command-line
                    
                    if app.ComputeTCsolutionforeverypointCheckBox.Value
                        % If requested, compute the TC solution as well and
                        % print an overview to the command-line
                        
                        [Data_TC, Data]=MAGEMin_to_TC('./ThermoCalc','tc-testMAGEMin.txt',Data); % compute TC
                        
                        Print_TC_vs_MAGEMin(Data,Data_TC); % print comparison
                        
                        assignin('base','Data_TC', Data_TC);                 % send info of current point to matlab command-line
                        
                    end
                    
                    if app.AddphaselabelsCheckBox.Value
                        % if requested, add label with stable solution on
                        % plot
                        n=length(StablePhases);
                        if mod(n,2)==0
                            StablePhases = reshape(StablePhases,n/2,2);
                        elseif mod(n,3)==0
                            StablePhases = reshape(StablePhases,n/3,3);
                        elseif mod(n,4)==0
                            StablePhases = reshape(StablePhases,n/4,4);
                        else
                            StablePhases = StablePhases;
                        end
                        
                        % make string array of it
                        StablePhases_text= [];
                        for i=1:size(StablePhases,1)
                            str = [];
                            for j=1:size(StablePhases,2)
                                str = [str, char(StablePhases(i,j)),' '];
                            end
                            StablePhases_text = [StablePhases_text; string(str)];
                        end

                        Nstep = 30;
                        dX = diff(app.UIAxes.XLim)/Nstep;
                        dY = diff(app.UIAxes.YLim)/Nstep;
                        Sx=1; Sy=1;
                        
                        if PseudoSectionData.Computation.DiagramType == 'PT'
                            XX = T;
                            YY = P;
                        elseif PseudoSectionData.Computation.DiagramType == 'PX'
                            XX = X;
                            YY = P;
                        elseif PseudoSectionData.Computation.DiagramType == 'TX'
                            XX = X;
                            YY = T;
                        end
                        
                        % Add line (for small fields)
                        if app.AddlabellineCheckBox.Value
                            h  = plot(app.UIAxes,[XX XX+Sx*dX],[YY YY+Sy*dY],'linewidth',1,'color','k');
                            app.hText = [app.hText; h];   % store handle to text
                        else
                            Sx=0; Sy=0;
                        end
                        
                        % textbox
                        h  = text(app.UIAxes,XX+1.01*Sx*dX,YY+Sy*dY,StablePhases_text, 'Edgecolor','k','BackgroundColor',[.9 .9 .9],'Color','k');
                        app.hText = [app.hText; h];   % store handle to text

                        %                         % Estimate size of field that we just clicked
                        %                         SizeField = length(find(PseudoSectionData.NumAssemblage==NumAssemblage))/length(PseudoSectionData.NumAssemblage);
                        %
                        %                         if SizeField<0.015
                        %                            plotLine = true;
                        %                            Sx=1; Sy=1;
                        %                         else
                        %                            Sx=0; Sy=0;
                        %                            plotLine = false;
                        %                         end
                        %
                        % line
                    end
                    
                end
                
            end


        end

        % Button down function: MAGEMinPseudoSectionViewerUIFigure
        function Retrieve_PT(app, event)
            
        end

        % Value changed function: DisplayfieldDropDown
        function PlotColor(app, event)
            
            
            dataPl  = app.PlotData;
            data    = app.PseudoSectionData;
            value   = app.DisplayfieldDropDown.Value;
            switch value
                case 'liquid fraction'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.liq(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'liquid fraction')
                    
                case '# of stable phases'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.numPhase(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'# stable phases')
                    %                     hold(app.UIAxes,'on')
                    %                     scatter(app.UIAxes,data.XY_vec(:,1),data.XY_vec(:,2),20,data.numPhase(:),'filled')
                case 'Variance'
                    numOxides = length(find(data.Chemistry.OxProp.("mol %")>0));
                    Variance = numOxides + 2 - data.numPhase;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Variance(:));
                    
                    caxis(app.UIAxes,[1 numOxides+1])
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Variance')
                    colormap(app.UIAxes, flipud(pink))
                    
                case 'Gibbs'
                    
                    % filter out extreme values
                    Gibbs = data.Gibbs;
                    ind = find(abs(Gibbs)>1e4);
                    Gibbs(ind) = NaN;
                    
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gibbs(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Gibbs Free Energy')
                    
                    
                case 'Gamma'
                    Gamma = data.Gamma(1,:);
                    
                    % filter out extreme values
                    ind = find(abs(Gamma)>1e4);
                    Gamma(ind) = NaN;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gamma(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Gamma[SiO2]')
                    
                case 'Density'
                    set(dataPl,'FaceVertexCData',data.Rho_tot(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Average density [kg/m3]')
                    
                case 'Density_liquid'
                    rho = data.Rho_liq(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Melt density [kg/m3]')
                    
                case 'Density_solid'
                    rho = data.Rho_sol(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Solid density [kg/m3]')
                    
                case 'Vp'
                    set(dataPl,'FaceVertexCData',data.Vp(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vp [km/s]')
                    
                case 'Vs'
                    set(dataPl,'FaceVertexCData',data.Vs(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vs [km/s]')
                    
                case 'Vp/Vs'
                    VpVs = data.Vp(:)./data.Vs(:);
                    ind = find(VpVs>3);
                    VpVs(ind) = NaN;
                    
                    set(dataPl,'FaceVertexCData',VpVs);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vp/Vs []')
                    
                case 'entropy'
                    set(dataPl,'FaceVertexCData',data.entropy(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'entropy [K/J]')
                    
                    
                case 'Status'
                    set(dataPl,'FaceVertexCData',data.Status(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Calculation status')
                    
                case 'NumAssemblage'
                    [~,iA,NumAssemblage_discrete] = unique(data.NumAssemblage(:));
                    
                    set(dataPl,'FaceVertexCData',NumAssemblage_discrete);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'discrete # of assemblage')
                    
                    
                case 'Field'
                    try
                        Field = evalin('base','Field');
                    catch
                        error('A variable "Field" should be present in the workspace of size(length(XY_vec),1)')
                        
                    end


                    % This is an arbitrary field that we can create in the
                    % command window. It should have the correct size
                    set(dataPl,'FaceVertexCData',Field);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Field created in the command-window')


            end
            
            Caxis_min =  min(get(dataPl,'FaceVertexCData'));
            Caxis_max =  max(get(dataPl,'FaceVertexCData'));
            switch value
                case 'Variance'
                    Caxis_min = 1;
                    Caxis_max = 11;
            end
            if isnan(Caxis_min)
                Caxis_min = 0;
            end
            if isnan(Caxis_max) | Caxis_max<=Caxis_min
                Caxis_max = Caxis_min+1;
            end
            
            
            app.Caxis_min.Value = Caxis_min;
            app.Caxis_max.Value = Caxis_max;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);


        end

        % Button pushed function: PickField1Button
        function PickField1(app, event)
            
            delete(app.hField1)
            
            % Retrieve the data of the last point
            PTnum   =   app.PTnum;
            if ~isempty(PTnum)
                P       =   PTnum(1);
                T       =   PTnum(2);
                num     =   PTnum(3);
                
                PseudoSectionData = app.PseudoSectionData;
                XY_vec  =   PseudoSectionData.XY_vec;
                ind     =   find( PseudoSectionData.NumAssemblage==num);
                
                hold(app.UIAxes, 'on')
                
                app.hField1 = plot(app.UIAxes,XY_vec(ind,1),XY_vec(ind,2),'wo','MarkerFaceColor','w','Tag',num2str(num));
                
            end
            
        end

        % Button pushed function: PickField2Button
        function PickField2(app, event)
            
            delete(app.hField2)
            
            % Retrieve the data of the last point
            PTnum   =   app.PTnum;
            if ~isempty(PTnum)
                P       =   PTnum(1);
                T       =   PTnum(2);
                num     =   PTnum(3);
                
                PseudoSectionData = app.PseudoSectionData;
                XY_vec  =   PseudoSectionData.XY_vec;
                ind     =   find( PseudoSectionData.NumAssemblage==num);
                
                hold(app.UIAxes, 'on')
                
                app.hField2 = plot(app.UIAxes,XY_vec(ind,1),XY_vec(ind,2),'mo','MarkerFaceColor','m','Tag',num2str(num));
                
            end
            
        end

        % Button pushed function: ExtractBoundButton
        function ExtractLine(app, event)
            if ~isempty(app.hField1) &  ~isempty(app.hField2)
                % only do this if both fields have been selected
                Field1_num = str2num(get(app.hField1,'Tag'));
                Field2_num = str2num(get(app.hField2,'Tag'));
                
                if Field1_num~=Field2_num % we can only do something if the two numbers differ
                    PseudoSectionData       =   app.PseudoSectionData;
                    NumAssemblage           =   PseudoSectionData.NumAssemblage;
                    PhaseData               =   PseudoSectionData.PhaseData;
                    elements                =   PseudoSectionData.elements;
                    
                    ind                     =   find(NumAssemblage==Field1_num);
                    Field1_StablePhases     =   PhaseData{ind(1)}.StableSolutions;
                    
                    ind                     =   find(NumAssemblage==Field2_num);
                    Field2_StablePhases     =   PhaseData{ind(2)}.StableSolutions;
                    
                    % Determine if the 2 fields are adjacent (aka, if there
                    % is indeed a reaction happening between them)
                    isReaction              =   false;
                    NumAssemblage_el        =   NumAssemblage(elements);
                    
                    % find elements that have both assemblages present,
                    % such that it is likely that there is a reaction
                    % between them
                    ind_El                  =   find(any(NumAssemblage_el==Field1_num,2) & ...
                        any(NumAssemblage_el==Field2_num,2));
                    if ~isempty(ind_El)
                        isReaction = true;
                    end
                    
                    % Display info about the reaction
                    Reaction                =   setxor(Field1_StablePhases, Field2_StablePhases)
                    if isReaction
                        XY_vec = PseudoSectionData.XY_vec;
                        % Display info about the reaction
                        for i=1:length(Reaction)
                            str = ['[',Reaction{i},']'];
                            app.ReactionlineTextArea.Value{i} = str;
                        end
                        
                        % Plot the approximate reaction points on either
                        % side of the reaction
                        el_React    =  elements(ind_El,:);
                        el_NumAssem =  NumAssemblage_el(ind_El,:);
                        
                        id                      =  find(el_NumAssem==Field1_num);
                        ind_Bound_Field1        =  el_React(id);
                        delete(app.hField1);
                        app.hField1 = plot(app.UIAxes,XY_vec(ind_Bound_Field1,1),XY_vec(ind_Bound_Field1,2),'wo','MarkerFaceColor','w','Tag',num2str(Field1_num));
                        
                        id                      =  find(el_NumAssem==Field2_num);
                        ind_Bound_Field2        =  el_React(id);
                        delete(app.hField2);
                        app.hField2 = plot(app.UIAxes,XY_vec(ind_Bound_Field2,1),XY_vec(ind_Bound_Field2,2),'mo','MarkerFaceColor','m','Tag',num2str(Field2_num));
                        
                        
                        % Store info in PseudoSectionData structure
                        PseudoSectionData.PhaseBound.Field1_num         = Field1_num;
                        PseudoSectionData.PhaseBound.Field2_num         = Field2_num;
                        PseudoSectionData.PhaseBound.ind_Bound_Field1   = ind_Bound_Field1;
                        PseudoSectionData.PhaseBound.ind_Bound_Field2   = ind_Bound_Field2;
                        PseudoSectionData.PhaseBound.Field1_TP          = XY_vec(ind_Bound_Field1,:);
                        PseudoSectionData.PhaseBound.Field2_TP          = XY_vec(ind_Bound_Field2,:);
                        
                        PseudoSectionData.PhaseBound.Field1_StablePhases    = Field1_StablePhases;
                        PseudoSectionData.PhaseBound.Field2_StablePhases    = Field2_StablePhases;
                        PseudoSectionData.PhaseBound.DifferingPhases        = Reaction;
                        
                        % Create a "LineData" structure that can be used to
                        % do TC line calculations
                        LineData.T_val(1) = min([PseudoSectionData.PhaseBound.Field1_TP(:,1); PseudoSectionData.PhaseBound.Field2_TP(:,1) ]);
                        LineData.P_val(1) = min([PseudoSectionData.PhaseBound.Field1_TP(:,2); PseudoSectionData.PhaseBound.Field2_TP(:,2) ]);
                        LineData.T_val(2) = max([PseudoSectionData.PhaseBound.Field1_TP(:,1); PseudoSectionData.PhaseBound.Field2_TP(:,1) ]);
                        LineData.P_val(2) = max([PseudoSectionData.PhaseBound.Field1_TP(:,2); PseudoSectionData.PhaseBound.Field2_TP(:,2) ]);
                        LineData.PhaseDisappear = Reaction;
                        PseudoSectionData.PhaseBound.LineData = LineData;
                        
                        
                        % Update min/max P & T fields in GUI:
                        app.PEditField.Value     = LineData.P_val(1);
                        app.PEditField_2.Value   = LineData.P_val(2);
                        app.TEditField.Value     = LineData.T_val(1);
                        app.PEditField_3.Value   = LineData.T_val(2);
                        
                        
                    else
                        % no reaction; delete the plots
                        delete(app.hField1);
                        delete(app.hField2);
                        app.ReactionlineTextArea.Value = '';
                        PseudoSectionData.PhaseBound.Field1_num = [];
                        PseudoSectionData.PhaseBound.Field2_num = [];
                        PseudoSectionData.PhaseBound.Field1_TP  = [];
                        PseudoSectionData.PhaseBound.Field2_TP  = [];
                        PseudoSectionData.PhaseBound.Field1_StablePhases    = [];
                        PseudoSectionData.PhaseBound.Field2_StablePhases    = [];
                        PseudoSectionData.PhaseBound.DifferingPhases        = [];
                        
                    end
                    
                    % update command-line info
                    app.PseudoSectionData =   PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    
                end
                
                
            end
            
        end

        % Button pushed function: ShowcellcentersselectpointsButton
        function ShowCellCenters(app, event)
            PseudoSectionData       =   app.PseudoSectionData;
            elements                =   PseudoSectionData.elements;
            XY_vec                  =   PseudoSectionData.XY_vec;
            T_vec = XY_vec(:,1);
            P_vec = XY_vec(:,2);
            
            T_cen = mean(T_vec(elements),2);
            P_cen = mean(P_vec(elements),2);
            
            if isempty(app.hCellCenter)
                hold(app.UIAxes, 'on')
                app.hCellCenter = plot(app.UIAxes,T_cen,P_cen,'w.');
                brush(app.UIAxes,'on')
            else
                delete(app.hCellCenter)
                app.hCellCenter=[];
                % also
                if isfield(PseudoSectionData,'Refine')
                    % Remove refined grid
                    delete(PseudoSectionData.Refine.PlotPatch);
                end
                
            end
            
            
            % now you can use the brush mode to select cells which you
            % want to refine
            
            
        end

        % Button pushed function: SaveButton
        function SaveAs(app, event)
            
            
            FileName                    =   get(app.FilenameEditField,'Value');
            PseudoSectionData           =   app.PseudoSectionData;                     % data of phase diagram
            PseudoSectionData.FileName  =   FileName;
            PseudoSectionData.Name      =   app.TitleEditField.Value;
            filename                    =   fullfile(app.FileDir,FileName);
            save(filename,'PseudoSectionData');                                          % save the data to a MAT file
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            app.FilenameEditField.FontColor  = 'r';  %change color to show that we saved it to a file
            
            
        end

        % Button pushed function: LoadButton
        function LoadFile(app, event)
            
            % Save the simulation under a specific name
            FileName                    =   get(app.FilenameEditField,'Value');         % initial simulation name
            filename                    =   fullfile(app.FileDir,[FileName,'.mat']);
            
            load(filename,'PseudoSectionData');                                         % load the data from a MAT file
            app.PseudoSectionData       =   PseudoSectionData;
            
            if isfield(PseudoSectionData,'Refine')
                delete(PseudoSectionData.Refine.PlotPatch);
                PseudoSectionData = rmfield(PseudoSectionData,'Refine');
            end
            
            % Update all values on display window accordingly
            app = FillInSimulationTab(app, PseudoSectionData);
            
            % Change color of filename, to indicate that we have loaded a
            % file
            app.FilenameEditField.FontColor = 'b';
            
            
            % Plot the phase diagram
            % update data for figure
            [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
            % update plot w/out deleting it (!). Otherwise, the button
            % click no longer works (matlab should fix that)
            set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
            h       = colorbar(app.UIAxes);
            ylabel(h,'# stable phases')
            
            
            app.PseudoSectionData       =   PseudoSectionData;
            
            % add TC lines if they are available in the struct you just
            % loaded
            plotLines = app.PlotCheckBox.Value;
            if isfield(app.PseudoSectionData,'LinesPlot') & plotLines;
                hold(app.UIAxes,'on')
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(app.PseudoSectionData, app.UIAxes, linecolor, linewidth);
            end
            
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % switch to the Tab that shows the plot
            app.TabGroup3.SelectedTab = app.PseudosectionTab;
            
            
        end

        % Button pushed function: SaveFileName
        function SaveFilename(app, event)
            
            % Save the simulation under a specific name
            FileName                    =   get(app.FilenameEditField,'Value');         % initial simulation name
            
            f=figure(); drawnow; f.Visible = 'off';
            [file,path]                 =   uigetfile('*.mat');                         % pick file UI
            delete(f);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';


            if file~=0
                app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
                app.FileName                =   file;
                app.FileDir                 =   path;
            end
            
            
        end

        % Button pushed function: RefineselectedareaButton
        function RefineMesh(app, event)
            % local mesh refinement of selected region
            PseudoSectionData   =   app.PseudoSectionData;
            
            refine_Elements     =   find(get(app.hCellCenter,'BrushData')); % get indices of cell centers
            elements            =   PseudoSectionData.elements;
            
            if isfield(PseudoSectionData,'Refine')
                Refine      =   PseudoSectionData.Refine;
                % we refined already before
                XY_vec      =   Refine.XY_vec;
                elements    =   Refine.elements;
                newPoints   =   Refine.newPoints;
                PrPts0      =   Refine.PrPts;
            else
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
            end
            
            % Refine
            irregular   =   zeros(0,3);
            numPoints   =   length(XY_vec);
            [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
            newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
            PrPts       = [PrPts0; PrPts];
            
            % Plot the refined mesh on top of the existing mesh
            delete(app.hCellCenter)  % delete cell center
            app.hCellCenter             =   [];
            PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
            
            % Info about # of new points
            app.newpointsTextArea.Value{1} = num2str(length(newPoints));
            
            % store data
            Refine.PlotPatch            =   PlotPatch;
            Refine.elements             =   elements;
            Refine.XY_vec               =   XY_vec;
            Refine.newPoints            =   newPoints;
            Refine.PrPts                =   PrPts;
            PseudoSectionData.Refine    =   Refine;
            
            app.PseudoSectionData       =   PseudoSectionData;
            app.newPoints               =   newPoints;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Button pushed function: StartcomputationButton
        function StartRefinement(app, event)
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            if isfield(PseudoSectionData,'Refine')
                % Perform a new computation for the refined grid
                Refine      =       PseudoSectionData.Refine;
                
                newPoints   =       Refine.newPoints;
                XY_vec      =       Refine.XY_vec;
                PrPts       =       Refine.PrPts;
                elements    =       Refine.elements;
                PhaseData   =       PseudoSectionData.PhaseData;
                Computation =       PseudoSectionData.Computation;
                
                % remove NumAssemblage from PhaseData (will be updated)
                for i=1:length(PhaseData)
                    PhaseData{i} = rmfield(PhaseData{i},'NumAssemblage');
                end
                
                % Update Gamma based on previous points
                PhaseData = Update_Gamma_onPoints(PhaseData,XY_vec, elements, newPoints,PrPts,Computation);
                
                % Add x-EOS interpolation (if possible):
                if Computation.Use_xEOS
                    PhaseData = Update_xEOS_onPoints(PhaseData,XY_vec, newPoints, PrPts,Computation);
                end
                
                % Add EM Fraction interpolation (if possible):
                if Computation.Use_EMFrac
                    PhaseData = Update_EMFrac_onPoints(PhaseData,XY_vec, newPoints,PrPts,Computation);
                end
                
                
                % Perform a MAGEMin simulation for all new points
                ComputeAllPoints    =  PseudoSectionData.Computation.AllPoints;
                UseGammaEstimation  =  PseudoSectionData.Computation.UseGammaEstimation;
                VerboseLevel        =  PseudoSectionData.Computation.Verbose;
                Chemistry           =  PseudoSectionData.Chemistry;
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Performing computations for refined mesh'],'Cancelable',"on");
                
                [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PseudoSectionData,PhaseData, newPoints, XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                
                if ~CancelComputation
                    
                    % Update info to new grid
                    PseudoSectionData.PhaseData =   PhaseData;
                    PseudoSectionData.XY_vec    =   XY_vec;
                    PseudoSectionData.elements  =   elements;
                    PseudoSectionData           =   rmfield(PseudoSectionData,'Refine');
                    
                    %                     PseudoSectionData           =   RedoSimulationFailedPoints(app, PseudoSectionData); % redo calculation for failed points
                    
                    % Remove refined grid
                    delete(Refine.PlotPatch);
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.newPoints               =   newPoints;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';  %change color of filename to show that it is a new computation
                    
                end
                
                
            end


        end

        % Button pushed function: RefineallphaseboundariesButton
        function RefinePhaseBounds(app, event)
            
            % refine all phase boundaries
            PseudoSectionData   =   app.PseudoSectionData;
            NumAssemblage       =   PseudoSectionData.NumAssemblage;
            
            if ~isfield(PseudoSectionData,'Refine')
                % if we already created a refined mesh we first have to
                % compute solutions @ every point to determine their phase
                % assemblage before we can refinee the phase boundaries
                
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
                
                % Reactions are when 2 points have a different assemblage number
                el_ext          =   elements(:,[1:4, 1]);         % circular extended elements
                Reactions       =   NumAssemblage(el_ext(:,2:end)) - NumAssemblage(el_ext(:,1:end-1));
                
                
                % Grid refinement where the reactions happen
                numReactions    = sum(abs(Reactions)>0,2);
                %refine_Elements = find(numReactions>1);     % reaction boundaries
                refine_Elements = find(any(abs(Reactions)>0,2));
                
                % Refine
                irregular   =   zeros(0,3);
                numPoints   =   length(XY_vec);
                [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
                newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
                PrPts       = [PrPts0; PrPts];
                
                % Plot the refined mesh on top of the existing mesh
                delete(app.hCellCenter)  % delete cell center
                app.hCellCenter             =   [];
                PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
                
                % Info about # of new points
                app.newpointsTextArea.Value{1} = num2str(length(newPoints));
                
                % store data
                Refine.RefinePhaseBounds    =   true;   % we can only refine this once; after this we need to start a computation
                Refine.PlotPatch            =   PlotPatch;
                Refine.elements             =   elements;
                Refine.XY_vec               =   XY_vec;
                Refine.newPoints            =   newPoints;
                Refine.PrPts                =   PrPts;
                PseudoSectionData.Refine    =   Refine;
                
                app.PseudoSectionData       =   PseudoSectionData;
                app.newPoints               =   newPoints;
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line            end
            else
                warning('First compute a new grid, before we can refine phase boundaries');
            end
        end

        % Value changed function: TitleEditField
        function ChangeTitle(app, event)
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                title(app.UIAxes,app.TitleEditField.Value)
            elseif value == 'PX'
                upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                title(app.UIAxes,upTitle)
            elseif value == 'TX'
                upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                title(app.UIAxes,upTitle)
            end
            
        end

        % Button pushed function: StartnewcomputationButton
        function StartNewComputation(app, event)

            delete(app.PlotPoint)

            PseudoSectionData       =   []; % new computation, hence this
            PseudoSectionData       =   GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            
            % Perform simulation
            PseudoSectionData.fig   =   app.MAGEMinPseudoSectionViewerUIFigure;
            DisplayPlots            =   true;
            [PseudoSectionData, CancelComputation]       =   ComputePhaseDiagrams_AMR(PseudoSectionData, DisplayPlots);

            if ~CancelComputation
                %                 PseudoSectionData       =   RedoSimulationFailedPoints(app, PseudoSectionData); % redo calculation for failed points
                app.PseudoSectionData   =   PseudoSectionData;
                PseudoSectionData       =   rmfield( PseudoSectionData, 'fig');     % remove figure data as this causes problems for saving otherwise
                
                % update data for figure
                [PseudoSectionData]      =   PlotInitialPseudoSection(app, PseudoSectionData);
                
                % update plot w/out deleting it (!). Otherwise, the button
                % click no longer works (matlab should fix that)
                set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
                
                app.PseudoSectionData     =   PseudoSectionData;
                
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                
                % switch to the Tab that shows the plot
                app.TabGroup3.SelectedTab = app.PseudosectionTab;
            end
  
            
        end

        % Button pushed function: ShowselectpointsButton
        function SelectRedoPoints(app, event)
            % redo the computation for selected points - here: select the
            % points
            
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            if isempty(app.hPoints)
                hold(app.UIAxes, 'on')
                app.hPoints = plot(app.UIAxes,T_vec,P_vec,'w.');
                brush(app.UIAxes,'on')
            else
                delete(app.hPoints)
                app.hPoints=[];
                
            end
            
        end

        % Button pushed function: StartRedoComputationButton
        function StartRedoComputation(app, event)
            % Redo the computation for selected points
            % This is mostly for debugging the code, as it will show us
            % how differences will affect the phase diagram
            
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            
            % Get a list of selected points
            selectedPoints           = find(get(app.hPoints,'BrushData'));               % get indices of all points to recompute
            
            if length(selectedPoints)>0
                P_sel   =   app.hPoints.YData(selectedPoints);
                T_sel   =   app.hPoints.XData(selectedPoints);
                
                % Discover to which points they correspond
                XY_vec          =   PseudoSectionData.XY_vec;
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                newPoints   =       dsearchn(TP_norm,[T_sel(:)/NormVal(1), P_sel(:)/NormVal(2)]);   % normalize, as it'll otherwise confuse delaunay
                
                elements    =       PseudoSectionData.elements;
                PhaseData   =       PseudoSectionData.PhaseData;
                Gamma       =       PseudoSectionData.Gamma;
                
                % remove NumAssemblage from PhaseData (will be updated)
                for i=1:length(PhaseData)
                    PhaseData{i} = rmfield(PhaseData{i},'NumAssemblage');
                end
                
                % Compute Gamma of 'redo' points by interpolating from
                % a non-selected nearby point
                
                % TO BE CHANGED TO USE NEARBY POINTS!


                %                 oldPoints               =   1:length(PseudoSectionData.XY_vec);
                %                 oldPoints(newPoints)    =   [];                                 % points that do not include the 'redo' points
                %                 elements_old            =   PseudoSectionData.elements;         % previous elements
                %                 for i=1:11
                %                     Gamma_new(i,:) = griddata(XY_vec(oldPoints,1)/NormVal(1),XY_vec(oldPoints,2)/NormVal(2),Gamma(i,oldPoints),XY_vec(newPoints,1)/NormVal(1),XY_vec(newPoints,2)/NormVal(2));
                %                 end
                %
                
                %                 for i=1:length(newPoints)
                %                     PhaseData{newPoints(i)}.Gamma = Gamma_new(:,i);
                %                 end
                
                
                % Perform a MAGEMin simulation for all new points
                ComputeAllPoints    =   PseudoSectionData.Computation.AllPoints;
                ComputeAllPoints    =   false;
                VerboseLevel        =   PseudoSectionData.Computation.Verbose;
                Chemistry           =   PseudoSectionData.Chemistry;
                UseGammaEstimation  =   PseudoSectionData.Computation.UseGammaEstimation;
                Computation         =   PseudoSectionData.Computation;
                
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redo computations for selected points'],'Cancelable',"on");
                
                for iPoint=1:length(newPoints)
                    PhaseData = Update_Gamma_onPoints(PhaseData,XY_vec, elements, newPoints(iPoint),[], PseudoSectionData.Computation);
                    
                    % Add x-EOS interpolation (if possible):
                    PhaseData = Update_xEOS_onPoints(PhaseData,XY_vec, newPoints(iPoint),[], PseudoSectionData.Computation);
                    
                    [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PhaseData, newPoints(iPoint), XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                    
                    
                    disp(['Percentage finished of REDO ',num2str(iPoint/length(newPoints)*100),'% '])
                    disp(' ')
                    if CancelComputation
                        break
                    end
                end
                if ~CancelComputation
                    % Update info to new grid
                    PseudoSectionData.PhaseData = PhaseData;
                    PseudoSectionData.XY_vec    = XY_vec;
                    PseudoSectionData.elements  = elements;
                    
                    % Remove selected points
                    delete(app.hPoints)
                    app.hPoints=[];
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.newPoints               =   newPoints;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';     %change color of filename to show that something new was computed
                end
                
            end
            
        end

        % Value changed function: Switch
        function ChemistrySelectionSwitch(app, event)
            value = app.Switch.Value;
            
            switch value
                case 'Preset bulk'
                    app.BulkCaption.Enable              = false;
                    app.SystemunitDropDown.Enable       = false;
                    app.LoadfromfileButton.Enable       = false;
                    app.PredefinedtestDropDown.Enable   = true;
                    app.UITableChemistry.Enable         = 'off';
                case 'Custom bulk'
                    app.BulkCaption.Enable              = true;
                    app.SystemunitDropDown.Enable       = true;
                    app.LoadfromfileButton.Enable       = true;
                    app.PredefinedtestDropDown.Enable   = false;
                    app.UITableChemistry.Enable         = 'on';
            end
            
            
        end

        % Button pushed function: RemovePointsButton
        function RemovePoints(app, event)
            delete(app.hField1);
            delete(app.hField2);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
            % open menu
            [file,path]                 =   uigetfile('*.mat');                         % pick file UI
            
            app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
            app.FileName                =   file;
            app.FileDir                 =   path;
            
            LoadFile(app, event);


        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            
            % save as menu
            f=figure(); drawnow; f.Visible = 'off';
            [file,path]                 =   uiputfile('*.mat');                         % pick file UI
            delete(f);
            
            figure(app.UIFigure)
            app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
            app.FileName                =   file;
            app.FileDir                 =   path;
            
            SaveAs(app, event);
            
        end

        % Menu selected function: ExporttoLaMEMGeoParamsMenu
        function ExporttoLaMEMGeoParamsMenuSelected(app, event)
            
            % Exports the data to a LaMEM file
            
            % save as menu
            [FileName,FileDir]      =   uiputfile('*.in');                         % pick file UI
            drawnow;
         %   figure(app.UIFigure)
            
            % Get grid resolution from UI
            GridResolution          =   [200 200];
            
            ExportData_LaMEM(app.PseudoSectionData, FileName, FileDir, GridResolution);


        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % Stops the UI
            closereq();
            
        end

        % Value changed function: ShowedgesSwitch
        function ShowedgesSwitchValueChanged(app, event)
            value = app.ShowedgesSwitch.Value;
            
            switch value
                case 'On'
                    set(app.PlotData,'LineStyle','-');
                case 'Off'
                    set(app.PlotData,'LineStyle','none');
            end


        end

        % Value changed function: ParallelcomputationsCheckBox
        function ParallelcomputationsCheckBoxValueChanged(app, event)
            value = app.ParallelcomputationsCheckBox.Value;
            
            if value
                Enable = "on";
            else
                Enable = "off";
            end
            value_switch = app.Binary_Switch.Value;
            
            
            app.MPIRanksEditFieldLabel_1.Enable = Enable;
            app.MPIRanksEditFieldLabel_2.Enable = Enable;
            if strcmp(value_switch,'Local')
                app.MPIRanksEditFieldLabel_3.Enable = Enable;
                app.MPIRanksEditFieldLabel_4.Enable = Enable;
                app.SelectMPIexec.Enable            = Enable;
            end
            app.PerformcomputationsonremoteserverCheckBox.Enable = Enable;
            
            
        end

        % Button pushed function: SelectMPIexec
        function SelectMPIexecButtonPushed(app, event)
            % Select an mpi executable
            [~,path]                                    =   uigetfile('*.*','Select your mpiexec file');       % pick file UI
            drawnow;
            figure(app.UIFigure)
            if path~=0
                app.MPIRanksEditFieldLabel_4.Value      =   path;                       % update path
            end
            
        end

        % Button pushed function: StartRedoStatusPoints
        function StartRedoStatusPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            Status_vec         =   PseudoSectionData.Status;
            
            ind = find(Status_vec==3);
            
            if app.PlotBrushData == 1
                % delete points from plot if they are plotted already
                delete(app.hPoints)
                app.PlotBrushData = 0;
                app.hPoints=[];
                
            elseif ~isempty(ind) & app.PlotBrushData==0
                % plot data
                hold(app.UIAxes, 'on')
                app.hPoints = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                app.PlotBrushData = 1;
            end


        end

        % Callback function
        function MinrequiredphasefractionCheckBoxValueChanged(app, event)
            
            
        end

        % Button pushed function: ReprocessPointsMinFraction
        function ReprocessPointsMinFractionButtonPushed(app, event)
            % This reprocesses the points based on the minimum fraction
            PseudoSectionData   = GetDataFromSimulationTab(app,app.PseudoSectionData);    % retrieve data from simulation
            
            % go through all points & reprocess them
            PseudoSectionData   = ReprocessPointsMinPhaseFraction(PseudoSectionData,PseudoSectionData.Computation.MinPhaseFraction);
            
            % Update arrays with density, # of phases etc.
            [PseudoSectionData] = PlotInitialPseudoSection(app,PseudoSectionData);
            
            % update plot w/out deleting it (!). Otherwise, the button
            % click no longer works (matlab should fix that)


            %             set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
            
            [~,iA,NumAssemblage_discrete] = unique(data.NumAssemblage(:));
            set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',NumAssemblage_discrete,'Edgecolor','k')
            h       = colorbar(app.UIAxes);
            ylabel(h,'discrete # of assemblage')
            
            
            app.PseudoSectionData       =   PseudoSectionData;
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % switch to the Tab that shows the plot
            app.TabGroup3.SelectedTab = app.PseudosectionTab;


        end

        % Button pushed function: ComputeTCLineButton
        function ComputeTCLineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                LinesPlot  =     PseudoSectionData.LinesPlot;
            else
                LinesPlot  =   [];
            end
            LineData          =     PseudoSectionData.PhaseBound.LineData;
            PhaseBound        =     PseudoSectionData.PhaseBound;
            
            
            % Read in data from the GUI:
            LineData.numPoints=     app.pointsEditField.Value;
            LineData.CalcTatP =     app.scanPCheckBox.Value;
            LineData.P_val(1) =     app.PEditField.Value;
            LineData.P_val(2) =     app.PEditField_2.Value;
            LineData.T_val(1) =     app.TEditField.Value;
            LineData.T_val(2) =     app.PEditField_3.Value;
            
            ComputeLine       =     logical(1);
            Data              =     PseudoSectionData.Data;
            
            assignin('base','LineData',LineData);    % send to matlab command-line
            
            % Compute line using TC
            Line              =     MAGEMin_to_TC('ThermoCalc','tc-testMAGEMin.txt',Data,logical(1),ComputeLine,LineData, PhaseBound);
            
            
            % Add line to structure
            LinesPlot{end+1} = Line;
            
            % Plot all lines if requested
            PseudoSectionData.LinesPlot = LinesPlot;
            
            plotLines = app.PlotCheckBox.Value;
            if ~isempty(Line.P)
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
                
                delete(app.hField1);
                delete(app.hField2);
            end
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            
        end

        % Button pushed function: DeletelastlineButton
        function DeletelastlineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            nLines = length(PseudoSectionData.LinesPlot);
            if nLines>1
                PseudoSectionData.LinesPlot(end) = [];
            else
                PseudoSectionData=rmfield(PseudoSectionData,'LinesPlot');
            end
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % call line plotting routine
            if isfield(app,'PlotPhaseBounds')
                h=findobj(app.UIAxes,'type','line');
                delete(h);    % remove all lines on plot
                app.PlotPhaseBounds=[];
            end
            plotLines = app.PlotCheckBox.Value;
            if plotLines
                delete(app.PlotPhaseBounds);
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
            end
            app.PseudoSectionData = PseudoSectionData;
            
        end

        % Value changed function: DatabaseListBox, 
        % PredefinedtestDropDown
        function PredefinedtestDropDownValueChanged(app, event)
            value     = app.PredefinedtestDropDown.Value;
%             database  = app.DatabaseDropDown.Value;
            database  = app.DatabaseListBox.Value;
            
            [OxideOxProportionsTable, Name] = GetPredefinedChemistry(app,str2num(value),database);
            
            app.UITableChemistry.Data = OxideOxProportionsTable;
            app.TitleEditField.Value  = Name;
        end

        % Value changed function: UsexEOSuponrefinementCheckBox
        function UsexEOSuponrefinementCheckBoxValueChanged(app, event)
            value = app.UsexEOSuponrefinementCheckBox.Value;
            if value
                app.xEOSDropDown.Enable     =   "on";
            else
                app.xEOSDropDown.Enable     =   "off";
            end
            
        end

        % Button pushed function: UniformrefinementButton
        function UniformrefinementButtonPushed(app, event)
            % local mesh refinement of selected region
            PseudoSectionData   =   app.PseudoSectionData;
            
            elements            =   PseudoSectionData.elements;
            refine_Elements     =   [1:length(elements)]';   % refine all elements
            
            if isfield(PseudoSectionData,'Refine')
                Refine      =   PseudoSectionData.Refine;
                % we refined already before
                XY_vec      =   Refine.XY_vec;
                elements    =   Refine.elements;
                newPoints   =   Refine.newPoints;
                PrPts0      =   Refine.PrPts;
            else
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
            end
            
            % Refine
            irregular   =   zeros(0,3);
            numPoints   =   length(XY_vec);
            [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
            newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
            PrPts       = [PrPts0; PrPts];
            
            % Plot the refined mesh on top of the existing mesh
            delete(app.hCellCenter)  % delete cell center
            app.hCellCenter             =   [];
            PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
            
            % Info about # of new points
            app.newpointsTextArea.Value{1} = num2str(length(newPoints));
            
            % store data
            Refine.PlotPatch            =   PlotPatch;
            Refine.elements             =   elements;
            Refine.XY_vec               =   XY_vec;
            Refine.newPoints            =   newPoints;
            Refine.PrPts                =   PrPts;
            PseudoSectionData.Refine    =   Refine;
            app.PseudoSectionData       =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Value changed function: xEOSDropDown
        function xEOSDropDownValueChanged(app, event)
            value = app.xEOSDropDown.Value;
            switch value
                case 'Average surrounding points'
                    app.num_Neighbours.Enable = "on";
                otherwise
                    app.num_Neighbours.Enable = "off";
            end
            
        end

        % Button pushed function: StartRedoComputationTCButton
        function StartRedoComputationTCButtonPushed(app, event)
            % Redo the computation for selected points
            % This is mostly for debugging the code, as it will show us
            % how differences will affect the phase diagram
            
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            
            % Get a list of selected points
            selectedPoints    = find(get(app.hPoints,'BrushData'));               % get indices of all points to recompute
            
            if length(selectedPoints)>0
                P_sel   =   app.hPoints.YData(selectedPoints);
                T_sel   =   app.hPoints.XData(selectedPoints);
                
                % Discover to which points they correspond
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                newPoints       =   dsearchn(TP_norm,[T_sel(:)/NormVal(1), P_sel(:)/NormVal(2)]);   % normalize, as it'll otherwise confuse delaunay
                
                % Progress dialog
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redoing pointwise calculations using ThermoCalc for ',num2str(length(newPoints)),' points'],'Cancelable',"on");
                
                % Perform calculations
                [PseudoSectionData, CancelCalculation] = RecomputePoints_TC(dlg,newPoints,PseudoSectionData);
                
                if ~CancelCalculation
                    
                    % Remove selected points
                    delete(app.hPoints)
                    app.hPoints=[];
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';     %change color of filename to show that something new was computed
                end
                
            end


        end

        % Button pushed function: SelectAllPoints
        function SelectAllPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            ind                 =   1:length(T_vec);
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                
                set(app.hPoints,'BrushData',ind);
                app.PlotBrushData = 1;
                
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
            
            
        end

        % Button pushed function: SelectAllNewPoints
        function SelectAllNewPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            newPoints          =   app.newPoints;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            ind                 =   newPoints;
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                
                set(app.hPoints,'BrushData',ind);
                app.PlotBrushData = 1;
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
            
            
        end

        % Button pushed function: SelectAllNewPoints_noTCButton
        function SelectAllNewPoints_noTCButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            PhaseData          =   PseudoSectionData.PhaseData;
            
            indTC = zeros(size(T_vec));
            for i=1:length(PhaseData)
                if isfield(PhaseData{i},'TC_recompute')
                    if PhaseData{i}.TC_recompute.success
                        indTC(i) = 1;
                    end
                end
                
            end
            
            ind_noTC = find(indTC==0);  % we did not reprocess this point yet with TC
            
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind_noTC),P_vec(ind_noTC),'wo');
                brush(app.UIAxes,'on')
                
                app.PlotBrushData = 1;
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
        end

        % Value changed function: GammaestimationCheckBox
        function GammaestimationCheckBoxValueChanged(app, event)
            value = app.GammaestimationCheckBox.Value;
            
            if value
                app.Gamma_Dropdown.Enable     =   "on";
            else
                app.Gamma_Dropdown.Enable     =   "off";
            end


        end

        % Button pushed function: DoLocalMinimizationsButton
        function DoLocalMinimizationsButtonPushed(app, event)
            % This will do a local optimization for a given data point
            % (taken from the command-window)
            
            % Read in data point from command windown
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in solution model
            SS = app.solutionmodelEditField.Value;
            
            % Read in n_pc
            n_pc = app.pointsdimensionEditField.Value;
            
            
            % Do the computations & perform clustering of the data
            MPI_dir   = app.MPIRanksEditFieldLabel_4.Value;
            MPI_cores = str2num(app.MPIRanksEditFieldLabel_2.Value);
            
            if MPI_cores==1
                MPI_dir=[];
            end
            [Local_best, Local_cluster, Local] = ScanParameterSpace_SolutionModel(Data.P,Data.T,Data.Gamma,SS, n_pc, MPI_dir, MPI_cores, false);
            
            
            % Send all data to command window
            assignin('base','Local',            Local);
            assignin('base','Local_best',       Local_best);
            assignin('base','Local_cluster',    Local_cluster);
            
            % Set options in dropdown menu based on # of Compositional
            % Variables
            nCompVar = size(Local.xEOS_end,2);
            for i=1:nCompVar
                strCompVar{i} = num2str(i);
            end
            if size(Local_cluster.xEOS_end,1)>1
                strCompVar{i+1} = 'clus. 1-2';
                i=i+1;
            end
            strCompVar{i+1} = 'random';
            app.DropDown_Local_Param1.Items                 = strCompVar;
            app.DropDown_Local_Param2.Items                 = strCompVar;
            
            % Set options in dropdown menu based on # of Compositional
            % Variables
            nCluster = size(Local_cluster.xEOS_end,1);
            for i=1:nCluster
                strClusterVar{i} = num2str(i);
            end
            
            
            app.clusterDropDown.Items                    = strClusterVar;


            app.DropDown_Local_Param2.Value                 = '2';
            app.DropDown_Local_Param1.Enable                = true;
            app.DropDown_Local_Param2.Enable                = true;
            app.PlotoutputLocalOptimizationButton.Enable    = true;
            app.CheckBox_holdon_local.Enable                = true;
            app.CheckBox_AddNN_local.Enable                 = true;
            app.CheckBox_FullSurface.Enable                 = true;
            app.Label_xaxis_Local.Enable                    = true;
            app.Label_yaxis_Local.Enable                    = true;
            app.keepnormalCheckBox.Enable   = true;
            
            app.Local_Xmin.Enable = true;
            app.Local_Xmax.Enable = true;
            app.Local_Xpts.Enable = true;
            app.Label_Xtext.Enable = true;
            
            app.Local_Ymin.Enable = true;
            app.Local_Ymax.Enable = true;
            app.Local_Ypts.Enable = true;
            app.Label_Ytext.Enable = true;
            
            app.PlotGsurface_Button.Enable = true;
            app.clusterDropDown.Enable = true;
            
            % Compute
            
        end

        % Button pushed function: PlotoutputLocalOptimizationButton
        function PlotoutputLocalOptimizationButtonPushed(app, event)
            % This creates a plot of all Gamma values and a second plot of
            % xeos(1) vs xeos(2)  (selectable through dropdowns)
            
            % Read in data point from command window
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in Local point from command window
            try
                Local = evalin('base','Local');
            catch
                error('A variable "Local" should be present in the workspace; click on a data point first!')
                
            end
            
            % Determine if we have the same SS model as part of the MAGEMin
            % output data as well
            SS              =   Local.SolutionModel;
            ind             =   find(strcmp(Data.StableSolutions,SS));
            if ~isempty(ind)
                HaveLocalxEOS   =   true;
                MAGEMin_xEOS    =   Data.CompositionalVar{ind};
            else
                HaveLocalxEOS   =   false;
            end
            numPoints = length(Local.Gamma(:));
            
            HoldOnPlot = app.CheckBox_holdon_local.Value;


            Add_NN     = app.CheckBox_AddNN_local.Value;
            
            switch app.DropDown_Local_Param1.Value
                case {'random','clus. 1-2'}
                    ind(1) = 1;
                otherwise
                    ind(1)  = str2num(app.DropDown_Local_Param1.Value);
            end
            
            switch app.DropDown_Local_Param2.Value
                case {'random','clus. 1-2'}
                    ind(2) = 1;
                otherwise
                    ind(2)  = str2num(app.DropDown_Local_Param2.Value);
            end
            
            
            figure(1), clf
            plot(Local.df), xlabel('# of points'), ylabel('Driving force')
            title(['Solution for ',SS],'FontSize',app.Title_Fontsize.Value)
            
            
            figure(2),
            
            
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            
            plot3(Local.xEOS_end(:,ind(1)), Local.xEOS_end(:,ind(2)),Local.df,'ro','MarkerFaceColor','r','MarkerSize',10)
            
            view(3)
            axis tight
            xlabel(['x-eos # ',num2str(ind(1)) ],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['x-eos # ',num2str(ind(2)) ],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force','Fontsize',app.Axes_Fontsize.Value);
            
            grid on;
            title_str = ['solution space scan (red markers) for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar  (',num2str(numPoints),' points)'];
            if HaveLocalxEOS
                hold on,
                plot3(MAGEMin_xEOS(ind(1)),MAGEMin_xEOS(ind(2)),0,'ks','Markersize',20)
                title_str = ['MAGEMin result (black square) vs. ', title_str];
            end
            
            if Add_NN
                % add predictions of the NN if requested
                xEOS_NN = xEOS_From_NN(SS,[Data.T Data.P]);
                if ~isempty(xEOS_NN)
                    hold on,
                    plot3(xEOS_NN(ind(1)),xEOS_NN(ind(2)),0,'bv','Markersize',20)
                    title_str = ['NN prediction (blue triangle) vs. ', title_str];
                    
                    
                    assignin('base','xEOS_NN',xEOS_NN); % send to command-line for inspection
                end
            end
            
            title(title_str,'FontSize',app.Title_Fontsize.Value)
            
            % adapt axis
            xl = xlim; xl(1) = xl(1)-0.1; xl(2) = xl(2)+0.1; xlim(xl);
            yl = ylim; yl(1) = yl(1)-0.1; yl(2) = yl(2)+0.1; ylim(yl);
            zl = zlim; zl(1) = zl(1)-0.1; zl(2) = zl(2)+0.1; zlim(zl);
            

            % Create a plot versus endmember proportions
            figure(22),
            
            
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            %             if SampleG
            %                 if SmoothSurf
            %                     surface(Gsurface.xP_S,Gsurface.yP_S,Gsurface.zP_S,'FaceAlpha',.7);
            %                     shading interp, hold on,
            %                     contour3(Gsurface.xP_S,Gsurface.yP_S,Gsurface.zP_S,20,'k')
            %                 else
            %                     surface(Gsurface.xP, Gsurface.yP, Gsurface.zP,'FaceAlpha',.7)
            %                     shading interp, hold on,
            %                     contour3(Gsurface.xP,Gsurface.yP,Gsurface.zP,20,'k')
            %                 end
            %                 colorbar
            %
            %             else
            plot3(Local.Prop_end(:,ind(1)), Local.Prop_end(:,ind(2)),Local.df,'ro','MarkerFaceColor','r','MarkerSize',10)
            %             end
            view(3)
            axis tight
            xlabel(['EM proportion # ',num2str(ind(1)) ],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['EM proportion # ',num2str(ind(2)) ],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force','Fontsize',app.Axes_Fontsize.Value);
            
            grid on;
            title_str = ['solution space scan (red markers) for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar  (',num2str(numPoints),' points)'];
            
            
            title(title_str,'FontSize',12)
            
            % adapt axis
            xl = xlim; xl(1) = xl(1)-0.1; xl(2) = xl(2)+0.1; xlim(xl);
            yl = ylim; yl(1) = yl(1)-0.1; yl(2) = yl(2)+0.1; ylim(yl);
            zl = zlim; zl(1) = zl(1)-0.1; zl(2) = zl(2)+0.1; zlim(zl);
            
            
        end

        % Button pushed function: PlotGsurface_Button
        function PlotGsurface_ButtonPushed(app, event)
            % Computes a G-surface around the current point
            
            
            % Read in data point from command window
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in Local point from command window
            try
                Local_cluster = evalin('base','Local_cluster');
            catch
                error('A variable "Local" should be present in the workspace; click on a data point first!')
                
            end
            
            
            % Get info from GUI
            lb(1)       = app.Local_Xmin.Value;
            lb(2)       = app.Local_Ymin.Value;
            ub(1)       = app.Local_Xmax.Value;
            ub(2)       = app.Local_Ymax.Value;
            nPts(1)     = app.Local_Xpts.Value;
            nPts(2)     = app.Local_Ypts.Value;
            SS          = app.solutionmodelEditField.Value;
            HoldOnPlot  = app.CheckBox_holdon_local.Value;
            Cluster     = str2double(app.clusterDropDown.Value);
            KeepNormal  = app.keepnormalCheckBox.Value;
            
            if KeepNormal
                % Read the normal vector from the command-window
                try
                    NormalVector = evalin('base','NormalVector');
                catch
                    error('A variable "NormalVector" should be present in the workspace; Generate that first')
                end
                
            else
                % We generate a new normal vector
                x0              =   Local_cluster.xEOS_end(Cluster,:);
                Normalized(1)   =   true;
                switch app.DropDown_Local_Param1.Value
                    case 'clus. 1-2'
                        % between cluster 1 and 2
                        normal(1,:) = Local_cluster.xEOS_end(2,:)-Local_cluster.xEOS_end(1,:);
                        
                    case 'random'
                        normal(1,:) = rand(size(x0));
                        
                    otherwise
                        % use one of the x-eos as coordinate axis
                        ind             =   str2num(app.DropDown_Local_Param1.Value);
                        normal(1,:)     =   zeros(size(x0));
                        normal(1,ind)   =   1;
                        
                        Normalized(1)   =   false;
                end
                normal(1,:) = normal(1,:)/max(abs(normal(1,:)));
                
                Normalized(2)   =   true;
                switch app.DropDown_Local_Param2.Value
                    case 'clus. 1-2'
                        % between cluster 1 and 2
                        normal(2,:) = Local_cluster.xEOS_end(2,:)-Local_cluster.xEOS_end(1,:);
                        
                    case 'random'
                        normal(2,:) = rand(size(x0));
                        
                    otherwise
                        % use one of the x-eos as coordinate axis
                        ind             =   str2num(app.DropDown_Local_Param2.Value);
                        normal(2,:)     =   zeros(size(x0));
                        normal(2,ind)   =   1;
                        
                        Normalized(2)   =   false;
                end
                normal(2,:) = normal(2,:)/max(abs(normal(2,:)));
                
                
                NormalVector.x0         = x0;
                NormalVector.normal     = normal;
                NormalVector.Normalized = Normalized;
                
                
                % Save to command-window
                assignin('base','NormalVector',NormalVector);
                
            end


            % Sample solution space
            [Gsurface]  = SampleSolutionSpace(SS,Data.T,Data.P,Data.Gamma,NormalVector.x0,NormalVector.normal,lb, ub, nPts);
            assignin('base','Gsurface',Gsurface); % send to command-line for inspection
            
            if any(NormalVector.Normalized)
                figure(222)
                % we have a coordinate system that is normalized, in which
                % case we center it around (0,0), which is where our
                % cluster is situated. Note that doing a new calculation
                % centers this again
            else
                
                figure(2)
                
                % transfer the normalized x,y coordinates
                if ~NormalVector.Normalized(1)
                    id           = find(NormalVector.normal(1,:)>0);
                    Gsurface.x   = Gsurface.x + NormalVector.x0(id);
                end
                if ~NormalVector.Normalized(2)
                    id           = find(NormalVector.normal(2,:)>0);
                    Gsurface.y   = Gsurface.y + NormalVector.x0(id);
                end
                
                
            end
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            
            if app.CheckBox_FullSurface.Value
                Gsurface.x_full = Gsurface.x;
                Gsurface.y_full = Gsurface.y;
                ind=find(isnan(Gsurface.df_full));
                Gsurface.x_full(ind)=    NaN;
                Gsurface.y_full(ind)=   NaN;
                mesh(Gsurface.x_full, Gsurface.y_full, Gsurface.df_full,'FaceAlpha',.7)
                hold on
            end
            ind=find(isnan(Gsurface.z));
            Gsurface.x(ind)     =   NaN;
            Gsurface.y(ind)     =   NaN;
            
            surface(Gsurface.x, Gsurface.y, Gsurface.z,'FaceAlpha',.7)
            shading interp, hold on,
            contour3(Gsurface.x,Gsurface.y,Gsurface.z,20,'k')
            %             mesh(Gsurface.x, Gsurface.y, Gsurface.df_full,'FaceAlpha',.7)
            
            
            h = colorbar, view(3), grid on
            ylabel(h,'Driving force','Fontsize',app.Axes_Fontsize.Value)
            
            if any(NormalVector.Normalized)
                % plot the clusters as well, in normalized coordinates
                for iClust=1:size(Local_cluster.xEOS_end,1)
                    X = NormalVector.normal'\[Local_cluster.xEOS_end(iClust,:)-NormalVector.x0]';% coordinates in normalized space
                    hold on
                    plot3(X(1),X(2),Local_cluster.df(iClust),'ro','MarkerFaceColor','r','MarkerSize',10)
                end
                
                
            end
            
            
            xl= [min(Gsurface.x(:)) max(Gsurface.x(:))]; xlim(xl);
            yl= [min(Gsurface.y(:)) max(Gsurface.y(:))]; ylim(yl);
            
            zl = zlim;
            zl(1) = min([ min(Gsurface.z(:)) zl(1)]);
            zl(2) = max([ max(Gsurface.z(:)) zl(2)]);
            zlim(zl);
            
            
            xlabel(['x:  ',app.DropDown_Local_Param1.Value,' direction'],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['y:  ',app.DropDown_Local_Param2.Value,' direction'],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force');
            
            grid on;
            title_str = ['solution space scan for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar'];
            title(title_str,'FontSize',app.Title_Fontsize.Value)
            axis tight
            view(0,90)


        end

        % Callback function
        function PlotCheckBoxValueChanged(app, event)
            plotLines = app.PlotCheckBox.Value;
            
            if plotLines
                hold(app.UIAxes,'on')
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(app.PseudoSectionData, app.UIAxes, linecolor, linewidth);
            else
                h=findobj(app.UIAxes,'type','line');
                delete(h);    % remove all lines on plot
                app.PlotPhaseBounds=[];
                
            end


        end

        % Button pushed function: DeletealllinesButton
        function DeletealllinesButtonPushed(app, event)
            PseudoSectionData = app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                PseudoSectionData = rmfield(PseudoSectionData,'LinesPlot');
            end
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % call line plotting routine
            h   =   findobj(app.UIAxes,'type','line');
            delete(h);                              % remove all lines on plot
            app.PlotPhaseBounds = [];
            
            app.PseudoSectionData = PseudoSectionData;
        end

        % Value changed function: 
        % UseEMFractionuponrefinementCheckBox
        function UseEMFractionuponrefinementCheckBoxValueChanged(app, event)
            value = app.UseEMFractionuponrefinementCheckBox.Value;
            if value
                app.EMFracDropDown.Enable     =   "on";
            else
                app.EMFracDropDown.Enable     =   "off";
            end
            
            
        end

        % Value changed function: EMFracDropDown
        function EMFracDropDownValueChanged(app, event)
            value = app.EMFracDropDown.Value;
            switch value
                case 'Average surrounding points'
                    app.num_Neighbours_EMFrac.Enable = "on";
                otherwise
                    app.num_Neighbours_EMFrac.Enable = "off";
            end
            
        end

        % Value changed function: Axes_Fontsize
        function Axes_FontsizeValueChanged(app, event)
            set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
        end

        % Value changed function: Title_Fontsize
        function Title_FontsizeValueChanged(app, event)
            set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
        end

        % Callback function
        function ColorbarlabelValueChanged(app, event)
            value = app.Colorbarlabel.Value;
            h       = colorbar(app.UIAxes);
            ylabel(h,value)
            
            
        end

        % Value changed function: Caxis_max
        function Caxis_maxValueChanged(app, event)
            Caxis_max = app.Caxis_max.Value;
            Caxis_min = app.Caxis_min.Value;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);


        end

        % Value changed function: Caxis_min
        function Caxis_minValueChanged(app, event)
            Caxis_max = app.Caxis_max.Value;
            Caxis_min = app.Caxis_min.Value;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);
            
        end

        % Button pushed function: RefineunivariantpointsButton
        function RefineunivariantpointsButtonPushed(app, event)
            
            % refine all phase boundaries
            PseudoSectionData   =   app.PseudoSectionData;
            NumAssemblage       =   PseudoSectionData.NumAssemblage;
            
            
            if ~isfield(PseudoSectionData,'Refine')
                % if we already created a refined mesh we first have to
                % compute solutions @ every point to determine their phase
                % assemblage before we can refinee the phase boundaries
                
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
                
                % Reactions are when 2 points have a different assemblage number
                el_ext          =   elements(:,[1:4, 1]);         % circular extended elements
                Reactions       =   NumAssemblage(el_ext(:,2:end)) - NumAssemblage(el_ext(:,1:end-1));
                
                % Grid refinement where the reactions happen
                numReactions    = sum(abs(Reactions)>0,2);
                refine_Elements = find(numReactions>2);     % points where different fields occur
                
                % Refine
                irregular   =   zeros(0,3);
                numPoints   =   length(XY_vec);
                [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
                newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
                PrPts       = [PrPts0; PrPts];
                
                % Plot the refined mesh on top of the existing mesh
                delete(app.hCellCenter)  % delete cell center
                app.hCellCenter             =   [];
                PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
                
                % Info about # of new points
                app.newpointsTextArea.Value{1} = num2str(length(newPoints));
                
                % store data
                Refine.RefinePhaseBounds    =   true;   % we can only refine this once; after this we need to start a computation
                Refine.PlotPatch            =   PlotPatch;
                Refine.elements             =   elements;
                Refine.XY_vec               =   XY_vec;
                Refine.newPoints            =   newPoints;
                Refine.PrPts                =   PrPts;
                PseudoSectionData.Refine    =   Refine;
                
                app.PseudoSectionData       =   PseudoSectionData;
                app.newPoints               =   newPoints;
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line            end
            else
                warning('First compute a new grid, before we can refine phase boundaries');
            end


        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            switch value
                case {'Gray'}
                    colormap(app.UIAxes, flipud(gray))
                case {'Bone'}
                    colormap(app.UIAxes, flipud(bone))
                case {'Pink'}
                    colormap(app.UIAxes, flipud(pink))
                otherwise
                    colormap(app.UIAxes, value)
            end
            
        end

        % Button pushed function: ComputeLineButton
        function ComputeLineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                LinesPlot  =     PseudoSectionData.LinesPlot;
            else
                LinesPlot  =   [];
            end
            
            LineData          =     PseudoSectionData.PhaseBound.LineData;
            PhaseBound        =     PseudoSectionData.PhaseBound;
            
            
            % Read in data from the GUI:
            LineData.numPoints=     app.pointsEditField.Value;
            LineData.CalcTatP =     app.scanPCheckBox.Value;
            LineData.P_val(1) =     app.PEditField.Value;
            LineData.P_val(2) =     app.PEditField_2.Value;
            LineData.T_val(1) =     app.TEditField.Value;
            LineData.T_val(2) =     app.PEditField_3.Value;
            
            ComputeLine       =     logical(1);
            Data              =     PseudoSectionData.Data;
            
            assignin('base','LineData',LineData);       %   send to matlab command-line
            assignin('base','PhaseBound',PhaseBound);   % send to matlab command-line
            
            % Compute line using TC
            %Line              =     MAGEMin_to_TC('ThermoCalc','tc-testMAGEMin.txt',Data,logical(1),ComputeLine,LineData, PhaseBound);
            
            % Compute line using MAGEMin
            Line                = Extract_Reactions_MAGEMin(LineData, PhaseBound);
            
            % Add line to structure
            LinesPlot{end+1} = Line;
            
            % Plot all lines if requested
            PseudoSectionData.LinesPlot = LinesPlot;
            
            plotLines = app.PlotCheckBox.Value;
            if ~isempty(Line.P)
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
                
                delete(app.hField1);
                delete(app.hField2);
            end
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Button pushed function: removealllabelsButton
        function removealllabelsButtonPushed(app, event)
            delete(app.hText)
            app.hText = [];
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: removelastlabelButton
        function removelastlabelButtonPushed(app, event)
            delete(app.hText(end))
            app.hText(end) = [];
            if length(findobj(app.hText(end),'Type','Line'))>0
                % delete line as well
                delete(app.hText(end))
                app.hText(end) = [];
            end
        end

        % Menu selected function: InstallMAGEMinMenu
        function InstallMAGEMinMenuSelected(app, event)
            Install_MAGEMin;
        end

        % Menu selected function: OpeninnewFigureMenu
        function OpeninnewFigureMenuSelected(app, event)
            % This opens the current axes in new Figure
            fig             =   figure;
            set(fig,'OuterPosition',[706 369 855 727]);
            
            ax              =   axes;clf;
            new_handle      =   copyobj(app.UIAxes,fig);
            set(fig,'Color','w')
            set(gca,'Units','normalized')
            set(gca,'Position',[0.05 0.05 0.9 0.9])
        end

        % Value changed function: Binary_Switch
        function Binary_SwitchValueChanged(app, event)
            value = app.Binary_Switch.Value;
            switch value
                case 'Default'
                    app.MPIRanksEditFieldLabel_3.Enable=false;
                    app.MPIRanksEditFieldLabel_4.Enable=false;
                    app.SelectMPIexec.Enable=false;
                    app.PseudoSectionData.Computation.Julia_MAGEMin_binary = true;
                    
                case 'Local'
                    app.MPIRanksEditFieldLabel_3.Enable=true;
                    app.MPIRanksEditFieldLabel_4.Enable=true;
                    app.SelectMPIexec.Enable=true;
                    app.PseudoSectionData.Computation.Julia_MAGEMin_binary = false;
                    
                    
            end
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            
            sysunit = app.SystemunitDropDown.Value; %this gets the unit of bulk rock composition to load
            db      = string(app.DatabaseListBox.Value);
            [file,path] = uigetfile('*.dat');
            bulk_in = loadBulkFromFile(sysunit,file,path,db);
            app.UITableChemistry.Data = bulk_in;
            app.TitleEditField.Value = file(1:end-4);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end

        % Button pushed function: displayisocontourButton
        function displayIsocontourFunction(app, event)
            data.phase = app.phaseEditField.Value;
            data.min   = app.minEditField.Value;
            data.stp   = app.stepEditField.Value;
            data.max   = app.maxEditField.Value;
            data.res   = app.resEditField.Value;
            data.style = app.TCLines_LineColor_2.Value;
            data.lblC  = app.TCLines_LineColor_4.Value;
            data.FtSize= app.TCLines_LineColor_5.Value;
            data.mode  = app.DataListBox.Value; 
            data.em    = [];
            
            if string(data.mode) == "em frac"
                data.em = app.emnamesListBox.Value;
            end
            
            hold(app.UIAxes,'on');
            set(app.hCaptionHandle,'visible','on');
            [h,cap] = Plot_IsoContour(app.UIAxes,app.PseudoSectionData,data);  
            
            id = 0;
            for i=1:length(app.hContour)
                if isempty(app.hContour{i})
                    id = i;
                    break;
                end
            end
            if id == 0
                app.hContour{end+1}     = h;
                app.hContourLegendText  = [app.hContourLegendText,cap];            
                app.hContourHandle      = [app.hContourHandle,h];
            else
                app.hContour{id}            = h;
                app.hContourLegendText(id)  = cap;            
                app.hContourHandle(id)      = h;
            end
            app.hCaptionHandle      = legend(app.UIAxes,app.hContourHandle,app.hContourLegendText,'Location','NorthEast');
        end

        % Button pushed function: removeallButton
        function clearIsocontour(app, event)
            for i=1:length(app.hContour)
                delete(app.hContour{i});
                app.hContour{i} = [];
            end
            app.hContour = {};
            app.hContourHandle = [];
            app.hContourLegendText = [];
            set(app.hCaptionHandle,'visible','off')
        end

        % Callback function
        function changeEmList(app, event)
            app.emnamesListBox.Items = {'em1', 'em2'};
        end

        % Value changed function: DataListBox, phaseEditField
        function SelectContourMode(app, event)
            value = app.DataListBox.Value;
            switch value
                case 'mode'
                    app.emnamesListBox.Visible         = false;
                    app.emnamesListBox.Enable          = false;
                case 'em frac'
                    if app.phaseEditField.Value
                        em_names = Get_endmembers(app.UIAxes,app.PseudoSectionData, app.phaseEditField.Value);
                        app.emnamesListBox.Items = em_names;
                        
                        app.emnamesListBox.Visible     = true;
                        app.emnamesListBox.Enable      = true;
                    else
                        disp('! Enter a valid solution phase name before trying to display the end-member list')
                    end
            end
        end

        % Callback function
        function save2eps(app, event)

        
        end

        % Button pushed function: ExportfigureButton
        function ExportFigure(app, event)
            set(0,'DefaultFigureRenderer', 'painters');
            set(0,'defaultfigurecolor',[1 1 1]);
            
            fileName    = app.FileEditField.Value;
            fig         = figure();
            cb          = colorbar(app.UIAxes);
            ylabel(cb, string(app.DisplayfieldDropDown.Value))
            h           = copyUIAxes(app.UIAxes, fig,'colorbar',cb,'copyPosition', true);
            saveas(fig, fileName, 'epsc');
            close(fig);   
            set(0,'DefaultFigureColor','factory')
        end

        % Button pushed function: removelastsButton
        function clearlast(app, event)
            len = length(app.hContour);

            if len > 1
                i = len;
                while isempty(app.hContour{i})
                    i = i-1;
                end
                    delete(app.hContour{i});
                    app.hContour{i}     = [];
                    delete(app.hContourHandle(i))
                    app.hContourHandle(i)     = [];
                    app.hContourLegendText(i) = [];
                if i == 1
                    set(app.hCaptionHandle,'visible','off');
                    app.hContour = {};
                    app.hContourHandle = [];
                    app.hContourLegendText = [];
                end
            else
                delete(app.hContour{1});
                app.hContour = {};
                app.hContourHandle = [];
                app.hContourLegendText = [];
                set(app.hCaptionHandle,'visible','off');
            end

        end

        % Button pushed function: SaveequilibriumdataButton
        function savePointData(app, event)
            save_EquilibriumData(app.PseudoSectionData);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end

        % Value changed function: DiagramtypeListBox
        function changeDiagramType(app, event)
            PseudoSectionData = app.PseudoSectionData;
            value = app.DiagramtypeListBox.Value;
            
            if value == 'PT'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
                app.UITable.RowName             = {'P [kbar]'; 'T [C]'; ''};
                app.UIAxes.YLabel.String        = 'Pressure [kbar]';
                app.UIAxes.XLabel.String        = 'Temperature [Celcius]';
                
                title(app.UIAxes,app.TitleEditField.Value)
                
                app.TemperatureEditField.Enable = 'off';
                app.PressureEditField.Enable    = 'off';
                app.TemperatureLabel.Enable     = 'off';
                app.PressureLabel.Enable        = 'off';
                PseudoSectionData.Computation.DiagramType = 'PT';
            elseif value == 'PX'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.X; % Composition [frac]: min, max, step
                app.UITable.RowName             = {'P [kbar]'; 'X [frac]'; ''};
                app.UIAxes.YLabel.String        = 'Pressure [kbar]';
                app.UIAxes.XLabel.String        = 'X [frac]';
                
                upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                title(app.UIAxes,upTitle)
                
%                 app.hText = text(app.UIAxes,0.4,0.4,FixedLabel, 'Edgecolor','k','BackgroundColor',[.9 .9 .9],'Color','k');
                app.TemperatureEditField.Enable = 'on';
                app.PressureEditField.Enable    = 'off';
                app.TemperatureLabel.Enable     = 'on';
                app.PressureLabel.Enable        = 'off';
                PseudoSectionData.Computation.DiagramType = 'PX';
            elseif value == 'TX'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.X; % Composition [frac]: min, max, step
                app.UITable.RowName             = {'T [C]'; 'X [frac]'; ''};
                app.UIAxes.YLabel.String        = 'Temperature [Celcius]';
                app.UIAxes.XLabel.String        = 'X [frac]';
                
                upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                title(app.UIAxes,upTitle)
                
                app.TemperatureEditField.Enable = 'off';
                app.PressureEditField.Enable    = 'on';
                app.TemperatureLabel.Enable     = 'off';
                app.PressureLabel.Enable        = 'on';
                PseudoSectionData.Computation.DiagramType = 'TX';
            end
            
            drawnow;
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAGEMinPseudoSectionViewerUIFigure = uifigure('Visible', 'off');
            app.MAGEMinPseudoSectionViewerUIFigure.Name = 'MAGEMin PseudoSection Viewer';
            app.MAGEMinPseudoSectionViewerUIFigure.ButtonDownFcn = createCallbackFcn(app, @Retrieve_PT, true);
            app.FileMenu = uimenu(app.MAGEMinPseudoSectionViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.InstallMAGEMinMenu = uimenu(app.FileMenu);
            app.InstallMAGEMinMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallMAGEMinMenuSelected, true);
            app.InstallMAGEMinMenu.Text = 'Install MAGEMin';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.OpeninnewFigureMenu = uimenu(app.FileMenu);
            app.OpeninnewFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninnewFigureMenuSelected, true);
            app.OpeninnewFigureMenu.Text = 'Open in new Figure';
            app.ExporttoLaMEMGeoParamsMenu = uimenu(app.FileMenu);
            app.ExporttoLaMEMGeoParamsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoLaMEMGeoParamsMenuSelected, true);
            app.ExporttoLaMEMGeoParamsMenu.Text = 'Export to LaMEM/GeoParams';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TabGroup3 = uitabgroup(app.MAGEMinPseudoSectionViewerUIFigure);
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationinfoPanel = uipanel(app.SimulationTab);
            app.SimulationinfoPanel.Title = 'Simulation info';
            app.SaveButton = uibutton(app.SimulationinfoPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAs, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.SimulationinfoPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadButton.Text = 'Load';
            app.SaveFileName = uibutton(app.SimulationinfoPanel, 'push');
            app.SaveFileName.ButtonPushedFcn = createCallbackFcn(app, @SaveFilename, true);
            app.SaveFileName.Text = '...';
            app.DiagramtypeListBoxLabel = uilabel(app.SimulationinfoPanel);
            app.DiagramtypeListBoxLabel.Tooltip = {'PT, Pressure Temperature and fixed composition'; 'TX, Temperature Composition and fixed pressure'; 'PX, Pressure Composition and fixed temperature'};
            app.DiagramtypeListBoxLabel.Text = {'Diagram'; 'type'};
            app.DiagramtypeListBox = uilistbox(app.SimulationinfoPanel);
            app.DiagramtypeListBox.Items = {'PT', 'TX', 'PX'};
            app.DiagramtypeListBox.ValueChangedFcn = createCallbackFcn(app, @changeDiagramType, true);
            app.DiagramtypeListBox.Tooltip = {''};
            app.DiagramtypeListBox.Value = 'PT';
            app.SimulationParametersPanel = uipanel(app.SimulationTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.UITable = uitable(app.SimulationParametersPanel);
            app.UITable.ColumnName = {'from'; 'to'; 'step'};
            app.UITable.RowName = {'P [kbar]'; 'T [C]'; ''};
            app.UITable.ColumnEditable = [true true true true];
            app.UITable.Tooltip = {'Note that is is preferable to set the minimum pressure to 0.01 kbar'};
            app.CoarsegridresolutionLabel = uilabel(app.SimulationParametersPanel);
            app.CoarsegridresolutionLabel.Text = 'Coarse grid resolution';
            app.RefinementlevelsDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.RefinementlevelsDropDownLabel.Text = 'Refinement levels';
            app.RefinementlevelsDropDown = uidropdown(app.SimulationParametersPanel);
            app.RefinementlevelsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RefinementlevelsDropDown.Value = '8';
            app.VerboselevelDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.VerboselevelDropDownLabel.Text = 'Verbose level';
            app.VerboselevelDropDown = uidropdown(app.SimulationParametersPanel);
            app.VerboselevelDropDown.Items = {'0', '-1', '1'};
            app.VerboselevelDropDown.Tooltip = {'-1, no verbose'; '0, minimum verbose, 1, maximum verbose'};
            app.VerboselevelDropDown.Value = '0';
            app.ParallelcomputationsCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.ParallelcomputationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelcomputationsCheckBoxValueChanged, true);
            app.ParallelcomputationsCheckBox.Text = 'Parallel computations';
            app.ParallelcomputationsCheckBox.Value = true;
            app.GammaestimationCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.GammaestimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammaestimationCheckBoxValueChanged, true);
            app.GammaestimationCheckBox.Enable = 'off';
            app.GammaestimationCheckBox.Text = 'Gamma estimation';
            app.MPIRanksEditFieldLabel_2 = uieditfield(app.SimulationParametersPanel, 'text');
            app.MPIRanksEditFieldLabel_2.Enable = 'off';
            app.MPIRanksEditFieldLabel_2.Value = '1';
            app.MPIRanksEditFieldLabel_1 = uilabel(app.SimulationParametersPanel);
            app.MPIRanksEditFieldLabel_1.Enable = 'off';
            app.MPIRanksEditFieldLabel_1.Text = '# of cores';
            app.MPIRanksEditFieldLabel_3 = uilabel(app.SimulationParametersPanel);
            app.MPIRanksEditFieldLabel_3.Enable = 'off';
            app.MPIRanksEditFieldLabel_3.Text = 'mpiexec path:';
            app.MPIRanksEditFieldLabel_4 = uieditfield(app.SimulationParametersPanel, 'text');
            app.MPIRanksEditFieldLabel_4.Enable = 'off';
            app.MPIRanksEditFieldLabel_4.Value = '/usr/bin/';
            app.SelectMPIexec = uibutton(app.SimulationParametersPanel, 'push');
            app.SelectMPIexec.ButtonPushedFcn = createCallbackFcn(app, @SelectMPIexecButtonPushed, true);
            app.SelectMPIexec.Enable = 'off';
            app.SelectMPIexec.Text = '...';
            app.UsexEOSuponrefinementCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.UsexEOSuponrefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsexEOSuponrefinementCheckBoxValueChanged, true);
            app.UsexEOSuponrefinementCheckBox.Enable = 'off';
            app.UsexEOSuponrefinementCheckBox.Text = 'Use x-EOS upon refinement';
            app.xEOSDropDown = uidropdown(app.SimulationParametersPanel);
            app.xEOSDropDown.Items = {'Inherit from coarse mesh', 'Average surrounding points', 'Average whole domain', 'Neural Network'};
            app.xEOSDropDown.ValueChangedFcn = createCallbackFcn(app, @xEOSDropDownValueChanged, true);
            app.xEOSDropDown.Enable = 'off';
            app.xEOSDropDown.Value = 'Inherit from coarse mesh';
            app.num_Neighbours = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.num_Neighbours.Enable = 'off';
            app.num_Neighbours.Value = 9;
            app.Gamma_Dropdown = uidropdown(app.SimulationParametersPanel);
            app.Gamma_Dropdown.Items = {'Inherit from coarse mesh', 'AMR interpolation', 'Neural Network 1'};
            app.Gamma_Dropdown.Enable = 'off';
            app.Gamma_Dropdown.Value = 'Inherit from coarse mesh';
            app.EMFracDropDown = uidropdown(app.SimulationParametersPanel);
            app.EMFracDropDown.Items = {'Inherit from coarse mesh', 'Average surrounding points', 'Average whole domain', 'Neural Network'};
            app.EMFracDropDown.ValueChangedFcn = createCallbackFcn(app, @EMFracDropDownValueChanged, true);
            app.EMFracDropDown.Enable = 'off';
            app.EMFracDropDown.Value = 'Inherit from coarse mesh';
            app.UseEMFractionuponrefinementCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.UseEMFractionuponrefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEMFractionuponrefinementCheckBoxValueChanged, true);
            app.UseEMFractionuponrefinementCheckBox.Enable = 'off';
            app.UseEMFractionuponrefinementCheckBox.Text = 'Use EM Fraction upon refinement';
            app.num_Neighbours_EMFrac = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.num_Neighbours_EMFrac.Enable = 'off';
            app.num_Neighbours_EMFrac.Value = 9;
            app.PerformcomputationsonremoteserverCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.PerformcomputationsonremoteserverCheckBox.Enable = 'off';
            app.PerformcomputationsonremoteserverCheckBox.Text = 'Perform computations on remote server';
            app.MAGEMinexecutableLabel = uilabel(app.SimulationParametersPanel);
            app.MAGEMinexecutableLabel.Text = 'MAGEMin executable:';
            app.Binary_Switch = uiswitch(app.SimulationParametersPanel, 'slider');
            app.Binary_Switch.Items = {'Default', 'Local'};
            app.Binary_Switch.ValueChangedFcn = createCallbackFcn(app, @Binary_SwitchValueChanged, true);
            app.Binary_Switch.Enable = 'off';
            app.Binary_Switch.Value = 'Default';
            app.SolverDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.SimulationParametersPanel);
            app.SolverDropDown.Items = {'PGE (default)', 'Legacy'};
            app.SolverDropDown.ItemsData = [1 0];
            app.SolverDropDown.Tooltip = {'By default the solver is PGE (Partionning Gibbs Energy). In some cases the solver might fail, mostly when Gibbs Duhem cannot be properly enforced. In these cases using the Legacy solver may help.'};
            app.SolverDropDown.Value = 1;
            app.PressureLabel = uilabel(app.SimulationParametersPanel);
            app.PressureLabel.Enable = 'off';
            app.PressureLabel.Text = 'Pressure';
            app.TemperatureLabel = uilabel(app.SimulationParametersPanel);
            app.TemperatureLabel.Enable = 'off';
            app.TemperatureLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.TemperatureEditField.Value = 800;
            app.PressureEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PressureEditField.Value = 10;
            app.ChemicalcompositionPanel = uipanel(app.SimulationTab);
            app.ChemicalcompositionPanel.Title = 'Chemical composition';
            app.UITableChemistry = uitable(app.ChemicalcompositionPanel);
            app.UITableChemistry.ColumnName = {'Oxide'; 'Fraction'; 'Fraction2'};
            app.UITableChemistry.RowName = {};
            app.UITableChemistry.ColumnEditable = [true true true];
            app.PredefinedtestDropDownLabel = uilabel(app.ChemicalcompositionPanel);
            app.PredefinedtestDropDownLabel.Text = 'Predefined test';
            app.PredefinedtestDropDown = uidropdown(app.ChemicalcompositionPanel);
            app.PredefinedtestDropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.PredefinedtestDropDown.ValueChangedFcn = createCallbackFcn(app, @PredefinedtestDropDownValueChanged, true);
            app.PredefinedtestDropDown.Value = '0';
            app.Switch = uiswitch(app.ChemicalcompositionPanel, 'slider');
            app.Switch.Items = {'Preset bulk', 'Custom bulk'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ChemistrySelectionSwitch, true);
            app.Switch.Value = 'Preset bulk';
            app.LoadfromfileButton = uibutton(app.ChemicalcompositionPanel, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Enable = 'off';
            app.LoadfromfileButton.Tooltip = {'The bulk composition should be provided in a ".dat" file with a header line such as "SiO2 Al2O3 ..." and the second line with the corresponding oxide content.'; 'Note that the input system unit is selected above and will be converted into mol.'; 'Example given in "/examples/bulk1.dat"'};
            app.LoadfromfileButton.Text = 'Load from file';
            app.SystemunitDropDown = uidropdown(app.ChemicalcompositionPanel);
            app.SystemunitDropDown.Items = {'mol', 'wt'};
            app.SystemunitDropDown.Enable = 'off';
            app.SystemunitDropDown.Tooltip = {'Don''t forget to select the right system unit before loading the file.'};
            app.SystemunitDropDown.Value = 'mol';
            app.BulkCaption = uilabel(app.ChemicalcompositionPanel);
            app.BulkCaption.Text = 'Bulk-rock composition (mol fraction)';
            app.TitleEditField = uieditfield(app.SimulationTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeTitle, true);
            app.TitleEditField.Value = 'KLB-1';
            app.TitleEditFieldLabel = uilabel(app.SimulationTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.FilenameEditField = uieditfield(app.SimulationTab, 'text');
            app.FilenameEditField.Value = 'test';
            app.FilenameEditFieldLabel = uilabel(app.SimulationTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.ThermodynamicsPanel = uipanel(app.SimulationTab);
            app.ThermodynamicsPanel.Title = 'Thermodynamics';
            app.DatabaseListBoxLabel = uilabel(app.ThermodynamicsPanel);
            app.DatabaseListBoxLabel.Text = 'Database';
            app.DatabaseListBox = uilistbox(app.ThermodynamicsPanel);
            app.DatabaseListBox.Items = {'Igneous (Holland et al., 2018)', 'Metapelite (White et al., 2014)'};
            app.DatabaseListBox.ItemsData = {'ig', 'mp'};
            app.DatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @PredefinedtestDropDownValueChanged, true);
            app.DatabaseListBox.Tooltip = {'Here you can select between available database. Note that the chemical system can change between database'; ''};
            app.DatabaseListBox.Value = 'ig';
            app.StartnewcomputationButton = uibutton(app.SimulationTab, 'push');
            app.StartnewcomputationButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewComputation, true);
            app.StartnewcomputationButton.Text = 'Start new computation';
            app.PseudosectionTab = uitab(app.TabGroup3);
            app.PseudosectionTab.Title = 'Pseudosection';
            app.NumPointsLabel = uilabel(app.PseudosectionTab);
            app.NumPointsLabel.Text = 'Points';
            app.ExportfigureButton = uibutton(app.PseudosectionTab, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigure, true);
            app.ExportfigureButton.Text = 'Export figure';
            app.FileEditField = uieditfield(app.PseudosectionTab, 'text');
            app.FileEditField.Value = './fig_name';
            app.FileEditFieldLabel = uilabel(app.PseudosectionTab);
            app.FileEditFieldLabel.Text = 'File:';
            app.UIAxes = uiaxes(app.PseudosectionTab);
            title(app.UIAxes, 'Pseudosection')
            xlabel(app.UIAxes, 'Temperature [Celcius]')
            ylabel(app.UIAxes, 'Pressure [kbar]')
            zlabel(app.UIAxes, 'Z')
            app.StableAssemblagePanel = uipanel(app.MAGEMinPseudoSectionViewerUIFigure);
            app.StableAssemblagePanel.Title = 'Stable Assemblage';
            app.UITableStableAssemblage = uitable(app.StableAssemblagePanel);
            app.UITableStableAssemblage.ColumnName = {'Stable Phase'; 'Mol fraction'};
            app.UITableStableAssemblage.RowName = {};
            app.UITableStableAssemblage.ColumnEditable = [false true];
            app.InformationPanel = uipanel(app.MAGEMinPseudoSectionViewerUIFigure);
            app.InformationPanel.Title = 'Information';
            app.TextAreaInfo = uitextarea(app.InformationPanel);
            app.SaveequilibriumdataButton = uibutton(app.InformationPanel, 'push');
            app.SaveequilibriumdataButton.ButtonPushedFcn = createCallbackFcn(app, @savePointData, true);
            app.SaveequilibriumdataButton.Enable = 'off';
            app.SaveequilibriumdataButton.Tooltip = {'This runs a single point calculation on the selected grid point and saves it using the "matlab_output" format'};
            app.SaveequilibriumdataButton.Text = 'Save equilibrium data ';
            app.DisplayfieldDropDownLabel = uilabel(app.MAGEMinPseudoSectionViewerUIFigure);
            app.DisplayfieldDropDownLabel.Text = 'Display field';
            app.DisplayfieldDropDown = uidropdown(app.MAGEMinPseudoSectionViewerUIFigure);
            app.DisplayfieldDropDown.Items = {'# of stable phases', 'Variance', 'liquid fraction', 'Gibbs', 'entropy', 'Gamma', 'Density', 'Density_liquid', 'Density_solid', 'Vp', 'Vs', 'Vp/Vs', 'Status', 'NumAssemblage', 'Field'};
            app.DisplayfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotColor, true);
            app.DisplayfieldDropDown.Value = '# of stable phases';
            app.TabGroup2 = uitabgroup(app.MAGEMinPseudoSectionViewerUIFigure);
            app.RefineTab = uitab(app.TabGroup2);
            app.RefineTab.Title = 'Refine';
            app.RefinegridcalculationsLabel = uilabel(app.RefineTab);
            app.RefinegridcalculationsLabel.Text = 'Refine grid calculations';
            app.StartcomputationButton = uibutton(app.RefineTab, 'push');
            app.StartcomputationButton.ButtonPushedFcn = createCallbackFcn(app, @StartRefinement, true);
            app.StartcomputationButton.Text = 'Start computation';
            app.ShowcellcentersselectpointsButton = uibutton(app.RefineTab, 'push');
            app.ShowcellcentersselectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCellCenters, true);
            app.ShowcellcentersselectpointsButton.Text = 'Show cell centers & select points';
            app.RefineselectedareaButton = uibutton(app.RefineTab, 'push');
            app.RefineselectedareaButton.ButtonPushedFcn = createCallbackFcn(app, @RefineMesh, true);
            app.RefineselectedareaButton.Text = 'Refine selected area';
            app.RefineallphaseboundariesButton = uibutton(app.RefineTab, 'push');
            app.RefineallphaseboundariesButton.ButtonPushedFcn = createCallbackFcn(app, @RefinePhaseBounds, true);
            app.RefineallphaseboundariesButton.Text = 'Refine all phase boundaries';
            app.newpointsTextAreaLabel = uilabel(app.RefineTab);
            app.newpointsTextAreaLabel.Text = '# new points';
            app.newpointsTextArea = uitextarea(app.RefineTab);
            app.newpointsTextArea.Value = {'0'};
            app.UniformrefinementButton = uibutton(app.RefineTab, 'push');
            app.UniformrefinementButton.ButtonPushedFcn = createCallbackFcn(app, @UniformrefinementButtonPushed, true);
            app.UniformrefinementButton.Text = 'Uniform refinement';
            app.RefineunivariantpointsButton = uibutton(app.RefineTab, 'push');
            app.RefineunivariantpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefineunivariantpointsButtonPushed, true);
            app.RefineunivariantpointsButton.Text = 'Refine univariant points';
            app.ExtractBoundariesTab = uitab(app.TabGroup2);
            app.ExtractBoundariesTab.Title = 'Extract Boundaries';
            app.PickField1Button = uibutton(app.ExtractBoundariesTab, 'push');
            app.PickField1Button.ButtonPushedFcn = createCallbackFcn(app, @PickField1, true);
            app.PickField1Button.Text = 'Pick Field 1';
            app.PickField2Button = uibutton(app.ExtractBoundariesTab, 'push');
            app.PickField2Button.ButtonPushedFcn = createCallbackFcn(app, @PickField2, true);
            app.PickField2Button.Text = 'Pick Field 2';
            app.ReactionlineTextArea = uitextarea(app.ExtractBoundariesTab);
            app.ReactionLabel = uilabel(app.ExtractBoundariesTab);
            app.ReactionLabel.Text = 'Reaction';
            app.ExtractBoundButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.ExtractBoundButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractLine, true);
            app.ExtractBoundButton.Text = 'Extract Bound';
            app.RemovePointsButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.RemovePointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePoints, true);
            app.RemovePointsButton.Text = 'Remove Points';
            app.ComputeTCLineButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.ComputeTCLineButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeTCLineButtonPushed, true);
            app.ComputeTCLineButton.Text = 'Compute TC Line';
            app.PEditFieldLabel = uilabel(app.ExtractBoundariesTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ExtractBoundariesTab, 'numeric');
            app.PEditField.Value = 1;
            app.PEditField_2 = uieditfield(app.ExtractBoundariesTab, 'numeric');
            app.PEditField_2.Value = 10;
            app.TEditFieldLabel = uilabel(app.ExtractBoundariesTab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.ExtractBoundariesTab, 'numeric');
            app.TEditField.Value = 500;
            app.PEditField_3 = uieditfield(app.ExtractBoundariesTab, 'numeric');
            app.PEditField_3.Value = 1000;
            app.scanPCheckBox = uicheckbox(app.ExtractBoundariesTab);
            app.scanPCheckBox.Text = 'scan P';
            app.scanPCheckBox.Value = true;
            app.pointsEditFieldLabel = uilabel(app.ExtractBoundariesTab);
            app.pointsEditFieldLabel.Text = '# points';
            app.pointsEditField = uieditfield(app.ExtractBoundariesTab, 'numeric');
            app.pointsEditField.Value = 50;
            app.DeletelastlineButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.DeletelastlineButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastlineButtonPushed, true);
            app.DeletelastlineButton.Text = 'Delete last line';
            app.DeletealllinesButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.DeletealllinesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletealllinesButtonPushed, true);
            app.DeletealllinesButton.Text = 'Delete all lines';
            app.ComputeLineButton = uibutton(app.ExtractBoundariesTab, 'push');
            app.ComputeLineButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeLineButtonPushed, true);
            app.ComputeLineButton.Text = 'Compute  Line';
            app.RedoTab = uitab(app.TabGroup2);
            app.RedoTab.Title = 'Redo';
            app.RedocomputationsforselectedpointsLabel = uilabel(app.RedoTab);
            app.RedocomputationsforselectedpointsLabel.Text = 'Redo computations for selected points';
            app.ShowselectpointsButton = uibutton(app.RedoTab, 'push');
            app.ShowselectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRedoPoints, true);
            app.ShowselectpointsButton.Text = 'Show & select points';
            app.fordebuggingLabel = uilabel(app.RedoTab);
            app.fordebuggingLabel.Text = '[for debugging]';
            app.StartRedoComputationButton = uibutton(app.RedoTab, 'push');
            app.StartRedoComputationButton.ButtonPushedFcn = createCallbackFcn(app, @StartRedoComputation, true);
            app.StartRedoComputationButton.Text = 'Start MAGEMin computation';
            app.StartRedoStatusPoints = uibutton(app.RedoTab, 'push');
            app.StartRedoStatusPoints.ButtonPushedFcn = createCallbackFcn(app, @StartRedoStatusPointsButtonPushed, true);
            app.StartRedoStatusPoints.Text = 'status=3';
            app.ReprocessPointsMinFraction = uibutton(app.RedoTab, 'push');
            app.ReprocessPointsMinFraction.ButtonPushedFcn = createCallbackFcn(app, @ReprocessPointsMinFractionButtonPushed, true);
            app.ReprocessPointsMinFraction.Text = 'Reprocess points MinFraction';
            app.SelectAllPoints = uibutton(app.RedoTab, 'push');
            app.SelectAllPoints.ButtonPushedFcn = createCallbackFcn(app, @SelectAllPointsButtonPushed, true);
            app.SelectAllPoints.Text = 'All points';
            app.StartRedoComputationTCButton = uibutton(app.RedoTab, 'push');
            app.StartRedoComputationTCButton.ButtonPushedFcn = createCallbackFcn(app, @StartRedoComputationTCButtonPushed, true);
            app.StartRedoComputationTCButton.Enable = 'off';
            app.StartRedoComputationTCButton.Text = 'Start TC computation';
            app.SelectAllNewPoints = uibutton(app.RedoTab, 'push');
            app.SelectAllNewPoints.ButtonPushedFcn = createCallbackFcn(app, @SelectAllNewPointsButtonPushed, true);
            app.SelectAllNewPoints.Text = 'All new points';
            app.SelectAllNewPoints_noTCButton = uibutton(app.RedoTab, 'push');
            app.SelectAllNewPoints_noTCButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllNewPoints_noTCButtonPushed, true);
            app.SelectAllNewPoints_noTCButton.Text = 'no TC';
            app.SolutionspaceTab = uitab(app.TabGroup2);
            app.SolutionspaceTab.Title = 'Solutionspace';
            app.AnalyzeSolutionSpaceLabel = uilabel(app.SolutionspaceTab);
            app.AnalyzeSolutionSpaceLabel.Text = 'Analyze Solution Space';
            app.solutionmodelEditFieldLabel = uilabel(app.SolutionspaceTab);
            app.solutionmodelEditFieldLabel.Text = 'solution model';
            app.solutionmodelEditField = uieditfield(app.SolutionspaceTab, 'text');
            app.solutionmodelEditField.Value = 'spn';
            app.pointsdimensionEditFieldLabel = uilabel(app.SolutionspaceTab);
            app.pointsdimensionEditFieldLabel.Text = '# points/dimension';
            app.pointsdimensionEditField = uieditfield(app.SolutionspaceTab, 'numeric');
            app.pointsdimensionEditField.Value = 5;
            app.ThiswillmakeanexhaustivesearchoftheLabel = uilabel(app.SolutionspaceTab);
            app.ThiswillmakeanexhaustivesearchoftheLabel.Text = 'Exhaustive search of parameter space ';
            app.DoLocalMinimizationsButton = uibutton(app.SolutionspaceTab, 'push');
            app.DoLocalMinimizationsButton.ButtonPushedFcn = createCallbackFcn(app, @DoLocalMinimizationsButtonPushed, true);
            app.DoLocalMinimizationsButton.Enable = 'off';
            app.DoLocalMinimizationsButton.Text = 'Compute';
            app.PlotoutputLocalOptimizationButton = uibutton(app.SolutionspaceTab, 'push');
            app.PlotoutputLocalOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotoutputLocalOptimizationButtonPushed, true);
            app.PlotoutputLocalOptimizationButton.Enable = 'off';
            app.PlotoutputLocalOptimizationButton.Text = 'Plot output';
            app.DropDown_Local_Param1 = uidropdown(app.SolutionspaceTab);
            app.DropDown_Local_Param1.Items = {'1', '2', '3', ''};
            app.DropDown_Local_Param1.Enable = 'off';
            app.DropDown_Local_Param1.Value = '1';
            app.DropDown_Local_Param2 = uidropdown(app.SolutionspaceTab);
            app.DropDown_Local_Param2.Items = {'random', '10', '10', '2', '3', '10', ''};
            app.DropDown_Local_Param2.Enable = 'off';
            app.DropDown_Local_Param2.Value = 'random';
            app.Label_xaxis_Local = uilabel(app.SolutionspaceTab);
            app.Label_xaxis_Local.Enable = 'off';
            app.Label_xaxis_Local.Text = 'x:';
            app.Label_yaxis_Local = uilabel(app.SolutionspaceTab);
            app.Label_yaxis_Local.Enable = 'off';
            app.Label_yaxis_Local.Text = 'y:';
            app.PlotGsurface_Button = uibutton(app.SolutionspaceTab, 'push');
            app.PlotGsurface_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotGsurface_ButtonPushed, true);
            app.PlotGsurface_Button.Enable = 'off';
            app.PlotGsurface_Button.Text = 'Plot G surface';
            app.Local_Xmin = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Xmin.ValueDisplayFormat = '%11.2f';
            app.Local_Xmin.Enable = 'off';
            app.Local_Xmin.Value = -0.5;
            app.Local_Xmax = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Xmax.ValueDisplayFormat = '%11.2f';
            app.Local_Xmax.Enable = 'off';
            app.Local_Xmax.Value = 1;
            app.Local_Xpts = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Xpts.RoundFractionalValues = 'on';
            app.Local_Xpts.ValueDisplayFormat = '%i';
            app.Local_Xpts.Enable = 'off';
            app.Local_Xpts.Value = 20;
            app.Label_Xtext = uilabel(app.SolutionspaceTab);
            app.Label_Xtext.Enable = 'off';
            app.Label_Xtext.Text = '#:';
            app.Local_Ypts = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Ypts.RoundFractionalValues = 'on';
            app.Local_Ypts.ValueDisplayFormat = '%i';
            app.Local_Ypts.Enable = 'off';
            app.Local_Ypts.Value = 20;
            app.Label_Ytext = uilabel(app.SolutionspaceTab);
            app.Label_Ytext.Enable = 'off';
            app.Label_Ytext.Text = '#:';
            app.Local_Ymax = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Ymax.ValueDisplayFormat = '%11.2f';
            app.Local_Ymax.Enable = 'off';
            app.Local_Ymax.Value = 1;
            app.Local_Ymin = uieditfield(app.SolutionspaceTab, 'numeric');
            app.Local_Ymin.ValueDisplayFormat = '%11.2f';
            app.Local_Ymin.Enable = 'off';
            app.Local_Ymin.Value = -0.5;
            app.clusterDropDownLabel = uilabel(app.SolutionspaceTab);
            app.clusterDropDownLabel.Text = 'cluster:';
            app.clusterDropDown = uidropdown(app.SolutionspaceTab);
            app.clusterDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.clusterDropDown.Enable = 'off';
            app.clusterDropDown.Value = '1';
            app.keepnormalCheckBox = uicheckbox(app.SolutionspaceTab);
            app.keepnormalCheckBox.Enable = 'off';
            app.keepnormalCheckBox.Text = 'keep normal';
            app.CheckBox_AddNN_local = uicheckbox(app.SolutionspaceTab);
            app.CheckBox_AddNN_local.Enable = 'off';
            app.CheckBox_AddNN_local.Text = 'add NN';
            app.CheckBox_holdon_local = uicheckbox(app.SolutionspaceTab);
            app.CheckBox_holdon_local.Enable = 'off';
            app.CheckBox_holdon_local.Text = 'hold on';
            app.CheckBox_FullSurface = uicheckbox(app.SolutionspaceTab);
            app.CheckBox_FullSurface.Enable = 'off';
            app.CheckBox_FullSurface.Text = 'full';
            app.ShowedgesSwitchLabel = uilabel(app.MAGEMinPseudoSectionViewerUIFigure);
            app.ShowedgesSwitchLabel.Text = 'Show edges';
            app.ShowedgesSwitch = uiswitch(app.MAGEMinPseudoSectionViewerUIFigure, 'slider');
            app.ShowedgesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowedgesSwitchValueChanged, true);
            app.ShowedgesSwitch.Value = 'On';
            app.ComputeTCsolutionforeverypointCheckBox = uicheckbox(app.MAGEMinPseudoSectionViewerUIFigure);
            app.ComputeTCsolutionforeverypointCheckBox.Text = 'Compute TC solution for every point';
            app.Image2 = uiimage(app.MAGEMinPseudoSectionViewerUIFigure);
            app.Image2.ImageSource = 'Logo_MagMin4.png';
            app.v131Label = uilabel(app.MAGEMinPseudoSectionViewerUIFigure);
            app.v131Label.Text = 'v1.3.1';
            app.Image = uiimage(app.MAGEMinPseudoSectionViewerUIFigure);
            app.Image.ImageSource = 'MAGMA_Logo.png';
            app.TabGroup4 = uitabgroup(app.MAGEMinPseudoSectionViewerUIFigure);
            app.PlotoptionsTab = uitab(app.TabGroup4);
            app.PlotoptionsTab.Title = 'Plot options';
            app.TCLines_LineColor_3 = uieditfield(app.PlotoptionsTab, 'text');
            app.TCLines_LineColor_3.Value = 'k';
            app.styleLabel_4 = uilabel(app.PlotoptionsTab);
            app.styleLabel_4.Text = 'style';
            app.TCLabel = uilabel(app.PlotoptionsTab);
            app.TCLabel.Text = 'TC :';
            app.widthEditFieldLabel_3 = uilabel(app.PlotoptionsTab);
            app.widthEditFieldLabel_3.Text = 'width';
            app.TCLines_LineWidth_3 = uieditfield(app.PlotoptionsTab, 'numeric');
            app.TCLines_LineWidth_3.Value = 0.1;
            app.PlotCheckBox = uicheckbox(app.PlotoptionsTab);
            app.PlotCheckBox.Text = 'Plot';
            app.PlotCheckBox.Value = true;
            app.PointLabel = uilabel(app.PlotoptionsTab);
            app.PointLabel.Text = 'Point:';
            app.sizeLabel = uilabel(app.PlotoptionsTab);
            app.sizeLabel.Text = 'size';
            app.SelectPt_MarkerSize = uieditfield(app.PlotoptionsTab, 'numeric');
            app.SelectPt_MarkerSize.Value = 20;
            app.styleLabel_5 = uilabel(app.PlotoptionsTab);
            app.styleLabel_5.Text = 'style';
            app.SelectPt_MarkerStyle = uieditfield(app.PlotoptionsTab, 'text');
            app.SelectPt_MarkerStyle.Value = 'r+';
            app.widthLabel = uilabel(app.PlotoptionsTab);
            app.widthLabel.Text = 'width';
            app.SelectPt_MarkerWidth = uieditfield(app.PlotoptionsTab, 'numeric');
            app.SelectPt_MarkerWidth.Value = 1.5;
            app.AxesFontsizeLabel = uilabel(app.PlotoptionsTab);
            app.AxesFontsizeLabel.Text = 'Axes Fontsize';
            app.Axes_Fontsize = uieditfield(app.PlotoptionsTab, 'numeric');
            app.Axes_Fontsize.ValueChangedFcn = createCallbackFcn(app, @Axes_FontsizeValueChanged, true);
            app.Axes_Fontsize.Value = 16;
            app.TitleFontsizeLabel = uilabel(app.PlotoptionsTab);
            app.TitleFontsizeLabel.Text = 'Title Fontsize';
            app.Title_Fontsize = uieditfield(app.PlotoptionsTab, 'numeric');
            app.Title_Fontsize.ValueChangedFcn = createCallbackFcn(app, @Title_FontsizeValueChanged, true);
            app.Title_Fontsize.Value = 20;
            app.ColorbarlabelLabel = uilabel(app.PlotoptionsTab);
            app.ColorbarlabelLabel.Text = 'Colorbar label';
            app.Colorbarlabel = uieditfield(app.PlotoptionsTab, 'text');
            app.ColoraxisLabel = uilabel(app.PlotoptionsTab);
            app.ColoraxisLabel.Text = 'Color axis';
            app.Caxis_min = uieditfield(app.PlotoptionsTab, 'numeric');
            app.Caxis_min.ValueChangedFcn = createCallbackFcn(app, @Caxis_minValueChanged, true);
            app.Caxis_min.Value = 1;
            app.Caxis_max = uieditfield(app.PlotoptionsTab, 'numeric');
            app.Caxis_max.ValueChangedFcn = createCallbackFcn(app, @Caxis_maxValueChanged, true);
            app.Caxis_max.Value = 7;
            app.ColormapDropDownLabel = uilabel(app.PlotoptionsTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.PlotoptionsTab);
            app.ColormapDropDown.Items = {'Parula', 'Gray', 'Bone', 'Pink', 'jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Pink';
            app.AddphaselabelsCheckBox = uicheckbox(app.PlotoptionsTab);
            app.AddphaselabelsCheckBox.Text = 'Add phase labels';
            app.removealllabelsButton = uibutton(app.PlotoptionsTab, 'push');
            app.removealllabelsButton.ButtonPushedFcn = createCallbackFcn(app, @removealllabelsButtonPushed, true);
            app.removealllabelsButton.Text = 'remove all labels';
            app.removelastlabelButton = uibutton(app.PlotoptionsTab, 'push');
            app.removelastlabelButton.ButtonPushedFcn = createCallbackFcn(app, @removelastlabelButtonPushed, true);
            app.removelastlabelButton.Text = 'remove last label';
            app.AddlabellineCheckBox = uicheckbox(app.PlotoptionsTab);
            app.AddlabellineCheckBox.Text = 'Add label line';
            app.AddlabellineCheckBox.Value = true;
            app.IsocontourTab = uitab(app.TabGroup4);
            app.IsocontourTab.Tooltip = {''};
            app.IsocontourTab.Title = 'Isocontour';
            app.DisplayisocontourLabel = uilabel(app.IsocontourTab);
            app.DisplayisocontourLabel.Text = 'Display isocontour';
            app.displayisocontourButton = uibutton(app.IsocontourTab, 'push');
            app.displayisocontourButton.ButtonPushedFcn = createCallbackFcn(app, @displayIsocontourFunction, true);
            app.displayisocontourButton.Text = 'display isocontour';
            app.removeallButton = uibutton(app.IsocontourTab, 'push');
            app.removeallButton.ButtonPushedFcn = createCallbackFcn(app, @clearIsocontour, true);
            app.removeallButton.Text = 'remove all';
            app.TCLines_LineColor_2 = uieditfield(app.IsocontourTab, 'text');
            app.TCLines_LineColor_2.Value = 'r-';
            app.StyleLabel = uilabel(app.IsocontourTab);
            app.StyleLabel.Text = 'Style';
            app.WidthLabel = uilabel(app.IsocontourTab);
            app.WidthLabel.Text = 'Width';
            app.TCLines_LineWidth_2 = uieditfield(app.IsocontourTab, 'numeric');
            app.TCLines_LineWidth_2.Value = 0.1;
            app.phaseEditField = uieditfield(app.IsocontourTab, 'text');
            app.phaseEditField.ValueChangedFcn = createCallbackFcn(app, @SelectContourMode, true);
            app.phaseEditFieldLabel = uilabel(app.IsocontourTab);
            app.phaseEditFieldLabel.Text = 'phase';
            app.minEditField = uieditfield(app.IsocontourTab, 'text');
            app.minEditField.Value = '0.0';
            app.minEditFieldLabel = uilabel(app.IsocontourTab);
            app.minEditFieldLabel.Text = 'min';
            app.stepEditField = uieditfield(app.IsocontourTab, 'text');
            app.stepEditField.Value = '0.1';
            app.stepEditFieldLabel = uilabel(app.IsocontourTab);
            app.stepEditFieldLabel.Text = 'step';
            app.maxEditField = uieditfield(app.IsocontourTab, 'text');
            app.maxEditField.Value = '1.0';
            app.maxEditFieldLabel = uilabel(app.IsocontourTab);
            app.maxEditFieldLabel.Text = 'max';
            app.resEditField = uieditfield(app.IsocontourTab, 'text');
            app.resEditField.Value = '50';
            app.resEditFieldLabel = uilabel(app.IsocontourTab);
            app.resEditFieldLabel.Text = 'res';
            app.DataListBox = uilistbox(app.IsocontourTab);
            app.DataListBox.Items = {'mode', 'em frac'};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @SelectContourMode, true);
            app.DataListBox.Value = 'mode';
            app.emnamesListBox = uilistbox(app.IsocontourTab);
            app.emnamesListBox.Items = {'em1', 'em2', 'em3', ''};
            app.emnamesListBox.Enable = 'off';
            app.emnamesListBox.Value = 'em1';
            app.TCLines_LineColor_4 = uieditfield(app.IsocontourTab, 'text');
            app.TCLines_LineColor_4.Value = 'black';
            app.ColorLabel = uilabel(app.IsocontourTab);
            app.ColorLabel.Text = 'Color';
            app.TCLines_LineColor_5 = uieditfield(app.IsocontourTab, 'text');
            app.TCLines_LineColor_5.Value = '8';
            app.FontsizeLabel = uilabel(app.IsocontourTab);
            app.FontsizeLabel.Text = 'Fontsize';
            app.Isopleth = uilabel(app.IsocontourTab);
            app.Isopleth.Text = 'Line';
            app.Iso_label = uilabel(app.IsocontourTab);
            app.removelastsButton = uibutton(app.IsocontourTab, 'push');
            app.removelastsButton.ButtonPushedFcn = createCallbackFcn(app, @clearlast, true);
            app.removelastsButton.Text = 'remove last(s)';
            % Show the figure after all components are created
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotPseudosection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAGEMinPseudoSectionViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAGEMinPseudoSectionViewerUIFigure)
        end
    end
end